classdef FIM_Rectilinear_Transformation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        ViewPanel                     matlab.ui.container.Panel
        yzButton                      matlab.ui.control.Button
        xzButton                      matlab.ui.control.Button
        xyButton                      matlab.ui.control.Button
        DefaultButton                 matlab.ui.control.Button
        TuningParameterSlider         matlab.ui.control.Slider
        TuningParameterSliderLabel    matlab.ui.control.Label
        NeighbourhoodSizeSlider       matlab.ui.control.Slider
        NeighbourhoodSizeSliderLabel  matlab.ui.control.Label
        RandomErrormmSlider           matlab.ui.control.Slider
        RandomErrormmLabel            matlab.ui.control.Label
        NormalEstimatedLabel          matlab.ui.control.Label
        TransformationAppliedLabel    matlab.ui.control.Label
        Lamp_4                        matlab.ui.control.Lamp
        Lamp_3                        matlab.ui.control.Lamp
        Lamp_2                        matlab.ui.control.Lamp
        Lamp                          matlab.ui.control.Lamp
        RecoverTranslationButton      matlab.ui.control.Button
        TranslationErrormEditField    matlab.ui.control.NumericEditField
        TranslationErrormLabel        matlab.ui.control.Label
        RotationErrorEditField        matlab.ui.control.NumericEditField
        RotationErrorLabel            matlab.ui.control.Label
        AIOptimizationMethodDropDown  matlab.ui.control.DropDown
        AIOptimizationMethodDropDownLabel  matlab.ui.control.Label
        RecoverRotationButton         matlab.ui.control.Button
        EstimateNormalButton          matlab.ui.control.Button
        SupervisedRegressionDropDown  matlab.ui.control.DropDown
        SupervisedRegressionDropDownLabel  matlab.ui.control.Label
        TranslationZmSlider           matlab.ui.control.Slider
        TranslationZmLabel            matlab.ui.control.Label
        TranslationYmSlider           matlab.ui.control.Slider
        TranslationYmLabel            matlab.ui.control.Label
        TranslationXmSlider           matlab.ui.control.Slider
        TranslationXmLabel            matlab.ui.control.Label
        RotationAngleSlider           matlab.ui.control.Slider
        RotationAngleLabel            matlab.ui.control.Label
        ApplyTransformationButton     matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        XP=[31.5392303500000	12.5000000000000	0	0	0	1
31.8122749300000	12.5000000000000	2.61999989000000	0	0	1
30.5358028400000	12.5000000000000	2.61999989000000	0	0	1
29.2611160300000	12.5000000000000	2.61999989000000	0	0	1
17.0931205700000	12.5000000000000	2.61999989000000	0	0	1
27.6193981200000	12.5000000000000	0	0	0	1
32.4643364000000	12.5085706700000	0	0	0	1
3.66144300000000	12.3828935600000	2.61999989000000	0	0	1
27.8061351800000	12.4882459600000	0	0	0	1
32.5889244100000	12.5182294800000	0	0	0	1
29.5052986100000	12.4913196600000	2.61999989000000	0	0	1
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28.0939731600000	12.3508348500000	1.67362106000000	-0.433933000000000	0.277533000000000	0.857133000000000];
        Att_BIM=[0,0,1,-2.7999999523
0,0,1,-2.6199998856
-0,0,1,-1.1620399952
0,0,1,-0.164045006
-0,-0,1,0
-0,-0,1,0.1800000072
0,1,0,-14.3999996185
-0,1,0,-14.1999998093
-0,1,-0,-13.8000001907
0,1,0,-9.1800003052
-0,1,-0,-8.779999733
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1,-0,-0,-0
];
        X_L=[30.5,12.5,1.8
22.5,12.5,1.8
16.5,12.5,1.8
10.5,12.5,1.8
4.5,12.5,1.8
30.5,6.5,1.8
22.5,6.5,1.8
16.5,6.5,1.8
10.5,6.5,1.8
4.5,6.5,1.8
0.5,6.5,1.8
26.5,2.5,1.8
22.5,2.5,1.8
16.5,2.5,1.8
10.5,2.5,1.8
4.5,2.5,1.8
0.5,2.5,1.8
24.5,-3.5,-1.5
12.5,-3.5,-1.5
0.5,-3.5,-1.5];
        Bounds=[29.4037,29.4037,29.4037,29.4037,0.2,5.4122,5.4122,0.2,0,0,2.62,2.62
29.6037,29.6037,29.6037,29.6037,5.4122,5.4122,0.2,0.2,2.62,0,0,2.62
29.4037,29.4037,29.6037,29.6037,0.2,5.4122,5.4122,0.2,0,0,0,0
29.4037,29.4037,29.6037,29.6037,0.2,0.2,0.2,0.2,0,2.62,2.62,0
29.4037,29.4037,29.6037,29.6037,5.4122,5.4122,5.4122,5.4122,2.62,0,0,2.62
29.4037,29.4037,29.6037,29.6037,0.2,5.4122,5.4122,0.2,2.62,2.62,2.62,2.62
29.4037,32.4,32.4,29.4037,5.6122,5.6122,5.6122,5.6122,0,0,2.62,2.62
29.4037,32.4,32.4,29.4037,5.4122,5.4122,5.4122,5.4122,2.62,2.62,-0,0
29.4037,29.4037,32.4,32.4,5.6122,5.4122,5.4122,5.6122,0,0,-0,0
32.4,32.4,32.4,32.4,5.6122,5.6122,5.4122,5.4122,2.62,0,-0,2.62
29.4037,29.4037,29.4037,29.4037,5.6122,5.6122,5.4122,5.4122,0,2.62,2.62,0
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14.5491,14.5491,14.5491,14.5491,8.58,9.18,9.18,8.58,2.62,2.62,0,0
];
        P % rotated points
        D_Var=[0.00483016157928058,366
0.00485730612175953,367
0.00534095198880235,386
0.00393484912213562,312
0.00430493956076036,423
0.00649638680027715,356
0.00465335267857434,346
0.00552537342067488,468
0.00798509319758996,380
0.0060521963638754,295
0.00462078995554604,468
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0.00310572748063811,382
];
        R_Var=[0.458887523855354,2.0069729936316
0.444314211529662,1.97453386543762
0.433791349053404,1.97553115961587
0.425546367091766,1.89335795507809
0.350141382092013,1.98259290271854
0.622697174398988,2.00846232886501
0.440447809778884,2.01189523183505
0.402432220135068,2.08661146371033
0.65222827486696,1.92507039293503
0.683861178896425,2.00245410904682
0.350002024958008,1.93342769708147
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0.249842682883328,1.81252717670653
];% Description
        R;
        Tf;
        % Description
        N_P; % Description
        FX; % Description
        GlobalBestPlot
        MeanPlot % Description
    end
    
    methods (Access = private)
        
        function [trainedModel, validationRMSE] = TrainingGR_Model(app,trainingData, responseData)

            inputTable = array2table(trainingData, 'VariableNames', {'column_1', 'column_2'});
            
            predictorNames = {'column_1', 'column_2'};
            predictors = inputTable(:, predictorNames);
            response = responseData;
            isCategoricalPredictor = [false, false];
            
            % Train a regression model
            % This code specifies all the model options and trains the model.
            regressionGP = fitrgp(...
                predictors, ...
                response, ...
                'BasisFunction', 'constant', ...
                'KernelFunction', 'squaredexponential', ...
                'Standardize', true);
            
            % Create the result struct with predict function
            predictorExtractionFcn = @(x) array2table(x, 'VariableNames', predictorNames);
            gpPredictFcn = @(x) predict(regressionGP, x);
            trainedModel.predictFcn = @(x) gpPredictFcn(predictorExtractionFcn(x));
            
            % Add additional fields to the result struct
            trainedModel.RegressionGP = regressionGP;
            trainedModel.About = 'This struct is a trained model exported from Regression Learner R2024a.';
            trainedModel.HowToPredict = sprintf('To make predictions on a new predictor column matrix, X, use: \n  yfit = c.predictFcn(X) \nreplacing ''c'' with the name of the variable that is this struct, e.g. ''trainedModel''. \n \nX must contain exactly 2 columns because this model was trained using 2 predictors. \nX must contain only predictor columns in exactly the same order and format as your training \ndata. Do not include the response column or any columns you did not import into the app. \n \nFor more information, see <a href="matlab:helpview(fullfile(docroot, ''stats'', ''stats.map''), ''appregression_exportmodeltoworkspace'')">How to predict using an exported model</a>.');
            
            % Extract predictors and response
            % This code processes the data into the right shape for training the
            % model.
            % Convert input to table
            inputTable = array2table(trainingData, 'VariableNames', {'column_1', 'column_2'});
            
            predictorNames = {'column_1', 'column_2'};
            predictors = inputTable(:, predictorNames);
            response = responseData;
            isCategoricalPredictor = [false, false];
            
            % Perform cross-validation
            partitionedModel = crossval(trainedModel.RegressionGP, 'KFold', 20);
            
            % Compute validation predictions
            % validationPredictions = kfoldPredict(partitionedModel);
            
            % Compute validation RMSE
            validationRMSE = sqrt(kfoldLoss(partitionedModel, 'LossFun', 'mse'));
        end

        function [trainedModel, validationRMSE] = TrainingSVM_Model(app,trainingData, responseData)

            inputTable = array2table(trainingData, 'VariableNames', {'column_1', 'column_2'});
            
            predictorNames = {'column_1', 'column_2'};
            predictors = inputTable(:, predictorNames);
            response = responseData;
            isCategoricalPredictor = [false, false];
            
            % Train a regression model
            % This code specifies all the model options and trains the model.
            responseScale = iqr(response);
            if ~isfinite(responseScale) || responseScale == 0.0
                responseScale = 1.0;
            end
            boxConstraint = responseScale/1.349;
            epsilon = responseScale/13.49;
            regressionSVM = fitrsvm(...
                predictors, ...
                response, ...
                'KernelFunction', 'polynomial', ...
                'PolynomialOrder', 3, ...
                'KernelScale', 'auto', ...
                'BoxConstraint', boxConstraint, ...
                'Epsilon', epsilon, ...
                'Standardize', true);
            
            % Create the result struct with predict function
            predictorExtractionFcn = @(x) array2table(x, 'VariableNames', predictorNames);
            svmPredictFcn = @(x) predict(regressionSVM, x);
            trainedModel.predictFcn = @(x) svmPredictFcn(predictorExtractionFcn(x));
            
            % Add additional fields to the result struct
            trainedModel.RegressionSVM = regressionSVM;
            trainedModel.About = 'This struct is a trained model exported from Regression Learner R2024a.';
            trainedModel.HowToPredict = sprintf('To make predictions on a new predictor column matrix, X, use: \n  yfit = c.predictFcn(X) \nreplacing ''c'' with the name of the variable that is this struct, e.g. ''trainedModel''. \n \nX must contain exactly 2 columns because this model was trained using 2 predictors. \nX must contain only predictor columns in exactly the same order and format as your training \ndata. Do not include the response column or any columns you did not import into the app. \n \nFor more information, see <a href="matlab:helpview(fullfile(docroot, ''stats'', ''stats.map''), ''appregression_exportmodeltoworkspace'')">How to predict using an exported model</a>.');
            
            % Extract predictors and response
            % This code processes the data into the right shape for training the
            % model.
            % Convert input to table
            inputTable = array2table(trainingData, 'VariableNames', {'column_1', 'column_2'});
            
            predictorNames = {'column_1', 'column_2'};
            predictors = inputTable(:, predictorNames);
            response = responseData;
            isCategoricalPredictor = [false, false];
            
            % Perform cross-validation
            KFolds = 20;
            cvp = cvpartition(size(response, 1), 'KFold', KFolds);
            % Initialize the predictions to the proper sizes
            validationPredictions = response;
            for fold = 1:KFolds
                trainingPredictors = predictors(cvp.training(fold), :);
                trainingResponse = response(cvp.training(fold), :);
                foldIsCategoricalPredictor = isCategoricalPredictor;
            
                % Train a regression model
                % This code specifies all the model options and trains the model.
                responseScale = iqr(trainingResponse);
                if ~isfinite(responseScale) || responseScale == 0.0
                    responseScale = 1.0;
                end
                boxConstraint = responseScale/1.349;
                epsilon = responseScale/13.49;
                regressionSVM = fitrsvm(...
                    trainingPredictors, ...
                    trainingResponse, ...
                    'KernelFunction', 'polynomial', ...
                    'PolynomialOrder', 3, ...
                    'KernelScale', 'auto', ...
                    'BoxConstraint', boxConstraint, ...
                    'Epsilon', epsilon, ...
                    'Standardize', true);
            
                % Create the result struct with predict function
                svmPredictFcn = @(x) predict(regressionSVM, x);
                validationPredictFcn = @(x) svmPredictFcn(x);
            
                % Add additional fields to the result struct
            
                % Compute validation predictions
                validationPredictors = predictors(cvp.test(fold), :);
                foldPredictions = validationPredictFcn(validationPredictors);
            
                % Store predictions in the original order
                validationPredictions(cvp.test(fold), :) = foldPredictions;
            end
            
            % Compute validation RMSE
            isNotMissing = ~isnan(validationPredictions) & ~isnan(response);
            validationRMSE = sqrt(nansum(( validationPredictions - response ).^2) / numel(response(isNotMissing) ));
        end

        function [trainedModel, validationRMSE] = TrainingKernel_Model(app,trainingData, responseData)

            inputTable = array2table(trainingData, 'VariableNames', {'column_1', 'column_2'});
            
            predictorNames = {'column_1', 'column_2'};
            predictors = inputTable(:, predictorNames);
            response = responseData;
            isCategoricalPredictor = [false, false];
            
            % Train a regression model
            % This code specifies all the model options and trains the model.
            regressionKernel = fitrkernel(...
                predictors, ...
                response, ...
                'Learner', 'leastsquares', ...
                'NumExpansionDimensions', 'auto', ...
                'Lambda', 'auto', ...
                'KernelScale', 'auto', ...
                'Standardize', true, ...
                'IterationLimit', 1000);
            
            % Create the result struct with predict function
            predictorExtractionFcn = @(x) array2table(x, 'VariableNames', predictorNames);
            kernelPredictFcn = @(x) predict(regressionKernel, x);
            trainedModel.predictFcn = @(x) kernelPredictFcn(predictorExtractionFcn(x));
            
            % Add additional fields to the result struct
            trainedModel.RegressionKernel = regressionKernel;
            trainedModel.About = 'This struct is a trained model exported from Regression Learner R2024a.';
            trainedModel.HowToPredict = sprintf('To make predictions on a new predictor column matrix, X, use: \n  yfit = c.predictFcn(X) \nreplacing ''c'' with the name of the variable that is this struct, e.g. ''trainedModel''. \n \nX must contain exactly 2 columns because this model was trained using 2 predictors. \nX must contain only predictor columns in exactly the same order and format as your training \ndata. Do not include the response column or any columns you did not import into the app. \n \nFor more information, see <a href="matlab:helpview(fullfile(docroot, ''stats'', ''stats.map''), ''appregression_exportmodeltoworkspace'')">How to predict using an exported model</a>.');
            
            % Extract predictors and response
            % This code processes the data into the right shape for training the
            % model.
            % Convert input to table
            inputTable = array2table(trainingData, 'VariableNames', {'column_1', 'column_2'});
            
            predictorNames = {'column_1', 'column_2'};
            predictors = inputTable(:, predictorNames);
            response = responseData;
            isCategoricalPredictor = [false, false];
            
            % Perform cross-validation
            KFolds = 20;
            cvp = cvpartition(size(response, 1), 'KFold', KFolds);
            % Initialize the predictions to the proper sizes
            validationPredictions = response;
            for fold = 1:KFolds
                trainingPredictors = predictors(cvp.training(fold), :);
                trainingResponse = response(cvp.training(fold), :);
                foldIsCategoricalPredictor = isCategoricalPredictor;
            
                % Train a regression model
                % This code specifies all the model options and trains the model.
                regressionKernel = fitrkernel(...
                    trainingPredictors, ...
                    trainingResponse, ...
                    'Learner', 'leastsquares', ...
                    'NumExpansionDimensions', 'auto', ...
                    'Lambda', 'auto', ...
                    'KernelScale', 'auto', ...
                    'Standardize', true, ...
                    'IterationLimit', 1000);
            
                % Create the result struct with predict function
                kernelPredictFcn = @(x) predict(regressionKernel, x);
                validationPredictFcn = @(x) kernelPredictFcn(x);
            
                % Add additional fields to the result struct
            
                % Compute validation predictions
                validationPredictors = predictors(cvp.test(fold), :);
                foldPredictions = validationPredictFcn(validationPredictors);
            
                % Store predictions in the original order
                validationPredictions(cvp.test(fold), :) = foldPredictions;
            end
            
            % Compute validation RMSE
            isNotMissing = ~isnan(validationPredictions) & ~isnan(response);
            validationRMSE = sqrt(nansum(( validationPredictions - response ).^2) / numel(response(isNotMissing) ));
        end

        function N=EstimateNormal(app,Fn,Att_P,R,D_Vars,R_Vars,sig,Neighb)
            Att_Pr=Att_P*R;
            
            lp=length(Att_P);
            
            if strcmp(Fn,'Gaussian Regression')
                ParamI = TrainingGR_Model(app,D_Vars, R_Vars(:,1));
                ParamII = TrainingGR_Model(app,D_Vars, R_Vars(:,2));
            elseif strcmp(Fn,'SVM Cubic')
                ParamI = TrainingSVM_Model(app,D_Vars, R_Vars(:,1));
                ParamII = TrainingSVM_Model(app,D_Vars, R_Vars(:,2));  
            else
                ParamI = TrainingKernel_Model(app,D_Vars, R_Vars(:,1));
                ParamII = TrainingKernel_Model(app,D_Vars, R_Vars(:,2)); 
            end
            
            A = ParamI.predictFcn([sig,Neighb]);
            B = ParamII.predictFcn([sig,Neighb]);
            r = wblrnd(A,B,lp,1);
            A_x=rand(lp,3); A_x=A_x./vecnorm(A_x,2,2);
            A_p=cross(Att_Pr(:,1:3),A_x);
            Att_Pn=zeros(lp,3);
            
            for i=1:lp
                Rt=Rodrigues_ang(A_p,r(i)*pi/720/2);
                Att_Pn(i,:)=Att_Pr(i,:)*Rt;
            end
            
            [~,r]=max(abs(Att_Pn(:,1:3)),[],2);
            Kk=length(r);
            m=[(1:Kk)',r];
            m=sub2ind([Kk,4],m(:,1),m(:,2));
            Att_Pn=sign(Att_Pn(m)).*Att_Pn;
            N=Att_Pn(:,1:3);
        end

        function [N_P,FX]=Significant_Normals(app,N,n)
            nm=15;
            [ff,xi,yi] = histcounts2(N(:,1),N(:,2),[n,n]);
            xi=(xi(1:end-1)+xi(2:end))/2;
            yi=(yi(1:end-1)+yi(2:end))/2;
            S = smoothdata2(ff,"gaussian",SmoothingFactor=0.5);
            [L,loc] = extrema2(app,S);
            pq=0.001; lq=find(L./max(L)>=pq); if length(lq)<=nm; lq=1:nm; elseif length(lq)>nm+10; lq=1:nm+10; end
            L_P=L(lq); L_P=L_P./max(L_P); loc_P=loc(lq);
            [mr,mc]=ind2sub([n,n],loc_P);
            Ns=createns(N(:,1:2)); ID=knnsearch(Ns,[xi(mr)',yi(mc)']);
            N_P=N(ID,:);
            FX=[xi(mr)',yi(mc)',ff(loc_P)];
        end

        function [x,Rr,Rot_err]=Rotation_Prediction(app,N_P,R,Kc,L_P)

            D_Pl=abs(N_P*N_P');
            
            nn=length(N_P);
            
            A=ones(nn);
            B=-2*Kc*ones(nn,1);
            d=Kc^2;
            M=10*Kc;
            Q=D_Pl-diag(L_P);
            qprob = qubo(Q + M*A, M*B, M*d);
            sol = solve(qprob);
            x=sol.BestX;
            
            % Recovered Rotation
            Nr=N_P(x==1,:);
            [~,rr(3)]=max(Nr(:,3));
            sr=setdiff(1:3,rr(3));
            rr(2)=sr(Nr(sr,1)<0);
            rr(1)=setdiff((1:3),rr(2:3));
            Nr=Nr(rr,:);
            Rz=Rodrigues(app,Nr(3,:),[0,0,1]);
            Nr=Nr*Rz;
            Nr=[Nr(:,1:2),[0;0;1]]; Nr=Nr./vecnorm(Nr,2,2);
            Rr=Rotation_Umeyama(app,Nr,eye(3));
            Rr=Rz*Rr;
            
            Rot_err=acosd((trace(R'*Rr)-1)/2);
        end

        function Rot=Rodrigues_ang(app,k,teta)
            K=[0,-k(3),k(2);k(3),0,-k(1);-k(2),k(1),0];
            R1=eye(3)+sin(teta)*K+(1-cos(teta))*K^2;
            Rot=R1';
        end
        
        function Rot=Rodrigues(app,N,V)
            teta=acos(dot(N,V)/norm(N)/norm(V));
            k=cross(N,V);
            if norm(k)==0
                Rot=eye(length(N),length(N));
            else
                k=k/norm(k);
                K=[0,-k(3),k(2);k(3),0,-k(1);-k(2),k(1),0];
                R1=eye(3)+sin(teta)*K+(1-cos(teta))*K^2;
                Rot=R1';
            end
        end

        function R=Rotation_Umeyama(app,Nd,Ns)
            % Nd: Destination Vector
            % Ns: Source Vector
            Ndd=Nd-mean(Nd);
            Nss=Ns-mean(Ns);
            % U*Nss'=Ndd
            M=Nss'*Ndd;
            [V,~,W]=svd(M);
            S=eye(size(Nd,2));
            if det(V*W)<0
                S(end,end)=-1;
            end
            R=V*S*W';
        end

        function [xymax,smax,xymin,smin] = extrema2(app,xy,varargin)
            % Copyright (c) 2016, Carlos Adrian Vargas Aguilera
            % All rights reserved.
            % 
            % Redistribution and use in source and binary forms, with or without
            % modification, are permitted provided that the following conditions are met:
            % 
            % * Redistributions of source code must retain the above copyright notice, this
            %   list of conditions and the following disclaimer.
            % 
            % * Redistributions in binary form must reproduce the above copyright notice,
            %   this list of conditions and the following disclaimer in the documentation
            %   and/or other materials provided with the distribution
            % THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS IS"
            % AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED TO, THE
            % IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE ARE
            % DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT OWNER OR CONTRIBUTORS BE LIABLE
            % FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL, EXEMPLARY, OR CONSEQUENTIAL
            % DAMAGES (INCLUDING, BUT NOT LIMITED TO, PROCUREMENT OF SUBSTITUTE GOODS OR
            % SERVICES; LOSS OF USE, DATA, OR PROFITS; OR BUSINESS INTERRUPTION) HOWEVER
            % CAUSED AND ON ANY THEORY OF LIABILITY, WHETHER IN CONTRACT, STRICT LIABILITY,
            % OR TORT (INCLUDING NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE
            % OF THIS SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.
            % Modified Code Developed by Carlos Adrian Vargas Aguilera
            % From       : http://www.mathworks.com/matlabcentral/fileexchange
            % File ID    : 12275
            % Carlos Adrian Vargas Aguilera (2025). extrema.m, extrema2.m 
            % (https://www.mathworks.com/matlabcentral/fileexchange/12275-extrema-m-extrema2-m), 
            % MATLAB Central File Exchange. Retrieved March 11, 2025.
            M = size(xy);
            if length(M) ~= 2
             error('Entry must be a matrix.')
            end
            N = M(2);
            M = M(1);
            
            % Search peaks through columns:
            [smaxcol,smincol] = extremos(app,xy);
            
            % Search peaks through rows, on columns with extrema points:
            im = unique([smaxcol(:,1);smincol(:,1)]); % Rows with column extrema
            [smaxfil,sminfil] = extremos(app,xy(im,:).');
            
            % Convertion from 2 to 1 index:
            smaxcol = sub2ind([M,N],smaxcol(:,1),smaxcol(:,2));
            smincol = sub2ind([M,N],smincol(:,1),smincol(:,2));
            smaxfil = sub2ind([M,N],im(smaxfil(:,2)),smaxfil(:,1));
            sminfil = sub2ind([M,N],im(sminfil(:,2)),sminfil(:,1));
            
            % Peaks in rows and in columns:
            smax = intersect(smaxcol,smaxfil);
            smin = intersect(smincol,sminfil);
            
            % Search peaks through diagonals?
            if nargin==1
             % Check peaks on down-up diagonal:
             [iext,jext] = ind2sub([M,N],unique([smax;smin]));
             [sextmax,sextmin] = extremos_diag(app,iext,jext,xy,1);
            
             % Check peaks on up-down diagonal:
             smax = intersect(smax,[M; (N*M-M); sextmax]);
             smin = intersect(smin,[M; (N*M-M); sextmin]);
            
             % Peaks on up-down diagonals:
             [iext,jext] = ind2sub([M,N],unique([smax;smin]));
             [sextmax,sextmin] = extremos_diag(app,iext,jext,xy,-1);
            
             % Peaks on columns, rows and diagonals:
             smax = intersect(smax,[1; N*M; sextmax]);
             smin = intersect(smin,[1; N*M; sextmin]);
            end
            
            % Extrema points:
            xymax = xy(smax);
            xymin = xy(smin);
            
            % Descending order:
            [~,inmax] = sort(-xymax);
            xymax = xymax(inmax);
            smax = smax(inmax);
            [xymin,inmin] = sort(xymin);
            smin = smin(inmin);
            
        end    
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            function [smax,smin] = extremos(app,matriz)
                % Copyright (c) 2016, Carlos Adrian Vargas Aguilera
                % All rights reserved.
                % 
                % Redistribution and use in source and binary forms, with or without
                % modification, are permitted provided that the following conditions are met:
                % 
                % * Redistributions of source code must retain the above copyright notice, this
                %   list of conditions and the following disclaimer.
                % 
                % * Redistributions in binary form must reproduce the above copyright notice,
                %   this list of conditions and the following disclaimer in the documentation
                %   and/or other materials provided with the distribution
                % THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS IS"
                % AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED TO, THE
                % IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE ARE
                % DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT OWNER OR CONTRIBUTORS BE LIABLE
                % FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL, EXEMPLARY, OR CONSEQUENTIAL
                % DAMAGES (INCLUDING, BUT NOT LIMITED TO, PROCUREMENT OF SUBSTITUTE GOODS OR
                % SERVICES; LOSS OF USE, DATA, OR PROFITS; OR BUSINESS INTERRUPTION) HOWEVER
                % CAUSED AND ON ANY THEORY OF LIABILITY, WHETHER IN CONTRACT, STRICT LIABILITY,
                % OR TORT (INCLUDING NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE
                % OF THIS SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.

                % Peaks through columns or rows.
                % Modified Code Developed by Carlos Adrian Vargas Aguilera
                % From       : http://www.mathworks.com/matlabcentral/fileexchange
                % File ID    : 12275
                % Carlos Adrian Vargas Aguilera (2025). extrema.m, extrema2.m 
                % (https://www.mathworks.com/matlabcentral/fileexchange/12275-extrema-m-extrema2-m), 
                % MATLAB Central File Exchange. Retrieved March 11, 2025.
            smax = [];
            smin = [];
            
            for n = 1:length(matriz(1,:))
             [~,imaxfil,~,iminfil] = extrema(app,matriz(:,n));
             if ~isempty(imaxfil)     % Maxima indexes
                  imaxcol = repmat(n,length(imaxfil),1);
                  smax = [smax; imaxfil imaxcol];
             end
             if ~isempty(iminfil)     % Minima indexes
                  imincol = repmat(n,length(iminfil),1);
                  smin = [smin; iminfil imincol];
             end
            end
            end
            
            function [sextmax,sextmin] = extremos_diag(app,iext,jext,xy,A)
                % Copyright (c) 2016, Carlos Adrian Vargas Aguilera
                % All rights reserved.
                % 
                % Redistribution and use in source and binary forms, with or without
                % modification, are permitted provided that the following conditions are met:
                % 
                % * Redistributions of source code must retain the above copyright notice, this
                %   list of conditions and the following disclaimer.
                % 
                % * Redistributions in binary form must reproduce the above copyright notice,
                %   this list of conditions and the following disclaimer in the documentation
                %   and/or other materials provided with the distribution
                % THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS IS"
                % AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED TO, THE
                % IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE ARE
                % DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT OWNER OR CONTRIBUTORS BE LIABLE
                % FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL, EXEMPLARY, OR CONSEQUENTIAL
                % DAMAGES (INCLUDING, BUT NOT LIMITED TO, PROCUREMENT OF SUBSTITUTE GOODS OR
                % SERVICES; LOSS OF USE, DATA, OR PROFITS; OR BUSINESS INTERRUPTION) HOWEVER
                % CAUSED AND ON ANY THEORY OF LIABILITY, WHETHER IN CONTRACT, STRICT LIABILITY,
                % OR TORT (INCLUDING NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE
                % OF THIS SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.

                % Modified Code Developed by Carlos Adrian Vargas Aguilera
                % From       : http://www.mathworks.com/matlabcentral/fileexchange
                % File ID    : 12275
                % Carlos Adrian Vargas Aguilera (2025). extrema.m, extrema2.m 
                % (https://www.mathworks.com/matlabcentral/fileexchange/12275-extrema-m-extrema2-m), 
                % MATLAB Central File Exchange. Retrieved March 11, 2025.
            [M,N] = size(xy);
            if A==-1
             iext = M-iext+1;
            end
            [iini,jini] = cruce(iext,jext,1,1);
            [iini,jini] = ind2sub([M,N],unique(sub2ind([M,N],iini,jini)));
            [ifin,jfin] = cruce(iini,jini,M,N);
            sextmax = [];
            sextmin = [];
            for n = 1:length(iini)
             ises = iini(n):ifin(n);
             jses = jini(n):jfin(n);
             if A==-1
              ises = M-ises+1;
             end
             s = sub2ind([M,N],ises,jses);
             [~,imax,~,imin] = extrema(app,xy(s));
             sextmax = [sextmax; s(imax)'];
             sextmin = [sextmin; s(imin)'];
            end
            end
            
            function [i,j] = cruce(app,i0,j0,I,J)
                % Copyright (c) 2016, Carlos Adrian Vargas Aguilera
                % All rights reserved.
                % 
                % Redistribution and use in source and binary forms, with or without
                % modification, are permitted provided that the following conditions are met:
                % 
                % * Redistributions of source code must retain the above copyright notice, this
                %   list of conditions and the following disclaimer.
                % 
                % * Redistributions in binary form must reproduce the above copyright notice,
                %   this list of conditions and the following disclaimer in the documentation
                %   and/or other materials provided with the distribution
                % THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS IS"
                % AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED TO, THE
                % IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE ARE
                % DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT OWNER OR CONTRIBUTORS BE LIABLE
                % FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL, EXEMPLARY, OR CONSEQUENTIAL
                % DAMAGES (INCLUDING, BUT NOT LIMITED TO, PROCUREMENT OF SUBSTITUTE GOODS OR
                % SERVICES; LOSS OF USE, DATA, OR PROFITS; OR BUSINESS INTERRUPTION) HOWEVER
                % CAUSED AND ON ANY THEORY OF LIABILITY, WHETHER IN CONTRACT, STRICT LIABILITY,
                % OR TORT (INCLUDING NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE
                % OF THIS SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.

                % Modified Code Developed by Carlos Adrian Vargas Aguilera
                % From       : http://www.mathworks.com/matlabcentral/fileexchange
                % File ID    : 12275
                % Carlos Adrian Vargas Aguilera (2025). extrema.m, extrema2.m 
                % (https://www.mathworks.com/matlabcentral/fileexchange/12275-extrema-m-extrema2-m), 
                % MATLAB Central File Exchange. Retrieved March 11, 2025.
                
                arriba = 2*(I*J==1)-1;
                
                si = (arriba*(j0-J) > arriba*(i0-I));
                i = (I - (J+i0-j0)).*si + J+i0-j0;
                j = (I+j0-i0-(J)).*si + J;
        
            end

            function [xmax,imax,xmin,imin] = extrema(app,x)
                % Copyright (c) 2016, Carlos Adrian Vargas Aguilera
                % All rights reserved.
                % 
                % Redistribution and use in source and binary forms, with or without
                % modification, are permitted provided that the following conditions are met:
                % 
                % * Redistributions of source code must retain the above copyright notice, this
                %   list of conditions and the following disclaimer.
                % 
                % * Redistributions in binary form must reproduce the above copyright notice,
                %   this list of conditions and the following disclaimer in the documentation
                %   and/or other materials provided with the distribution
                % THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS IS"
                % AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED TO, THE
                % IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE ARE
                % DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT OWNER OR CONTRIBUTORS BE LIABLE
                % FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL, EXEMPLARY, OR CONSEQUENTIAL
                % DAMAGES (INCLUDING, BUT NOT LIMITED TO, PROCUREMENT OF SUBSTITUTE GOODS OR
                % SERVICES; LOSS OF USE, DATA, OR PROFITS; OR BUSINESS INTERRUPTION) HOWEVER
                % CAUSED AND ON ANY THEORY OF LIABILITY, WHETHER IN CONTRACT, STRICT LIABILITY,
                % OR TORT (INCLUDING NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE
                % OF THIS SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.

                % Modified Code Developed by Carlos Adrian Vargas Aguilera
                % From       : http://www.mathworks.com/matlabcentral/fileexchange
                % File ID    : 12275
                % Carlos Adrian Vargas Aguilera (2025). extrema.m, extrema2.m 
                % (https://www.mathworks.com/matlabcentral/fileexchange/12275-extrema-m-extrema2-m), 
                % MATLAB Central File Exchange. Retrieved March 11, 2025.
                
                xmax = [];
                imax = [];
                xmin = [];
                imin = [];
                
                % Vector input?
                Nt = numel(x);
                if Nt ~= length(x)
                 error('Entry must be a vector.')
                end
                
                % NaN's:
                inan = find(isnan(x));
                indx = 1:Nt;
                if ~isempty(inan)
                 indx(inan) = [];
                 x(inan) = [];
                 Nt = length(x);
                end
                
                % Difference between subsequent elements:
                dx = diff(x);
                
                % Is an horizontal line?
                if ~any(dx)
                 return
                end
                
                % Flat peaks? Put the middle element:
                a = find(dx~=0);              % Indexes where x changes
                lm = find(diff(a)~=1) + 1;    % Indexes where a do not changes
                d = a(lm) - a(lm-1);          % Number of elements in the flat peak
                a(lm) = a(lm) - floor(d/2);   % Save middle elements
                a(end+1) = Nt;
                
                % Peaks?
                xa  = x(a);             % Serie without flat peaks
                b = (diff(xa) > 0);     % 1  =>  positive slopes (minima begin)  
                                        % 0  =>  negative slopes (maxima begin)
                xb  = diff(b);          % -1 =>  maxima indexes (but one) 
                                        % +1 =>  minima indexes (but one)
                imax = find(xb == -1) + 1; % maxima indexes
                imin = find(xb == +1) + 1; % minima indexes
                imax = a(imax);
                imin = a(imin);
                
                nmaxi = length(imax);
                nmini = length(imin);                
                
                % Maximum or minumim on a flat peak at the ends?
                if (nmaxi==0) && (nmini==0)
                 if x(1) > x(Nt)
                  xmax = x(1);
                  imax = indx(1);
                  xmin = x(Nt);
                  imin = indx(Nt);
                 elseif x(1) < x(Nt)
                  xmax = x(Nt);
                  imax = indx(Nt);
                  xmin = x(1);
                  imin = indx(1);
                 end
                 return
                end
                
                % Maximum or minumim at the ends?
                if (nmaxi==0) 
                 imax(1:2) = [1 Nt];
                elseif (nmini==0)
                 imin(1:2) = [1 Nt];
                else
                 if imax(1) < imin(1)
                  imin(2:nmini+1) = imin;
                  imin(1) = 1;
                 else
                  imax(2:nmaxi+1) = imax;
                  imax(1) = 1;
                 end
                 if imax(end) > imin(end)
                  imin(end+1) = Nt;
                 else
                  imax(end+1) = Nt;
                 end
                end
                xmax = x(imax);
                xmin = x(imin);
                
                % NaN's:
                if ~isempty(inan)
                 imax = indx(imax);
                 imin = indx(imin);
                end
                
                % Same size as x:
                imax = reshape(imax,size(xmax));
                imin = reshape(imin,size(xmin));
                
                % Descending order:
                [~,inmax] = sort(-xmax);
                xmax = xmax(inmax);
                imax = imax(inmax);
                [xmin,inmin] = sort(xmin);
                imin = imin(inmin);
            end

            function x=ModelFittingMethod(app,Fn,Cxb,Mt,Gn,Miter,D_Thresh,kk)
                ub=max(-min(Cxb,[],'all'),abs(Mt));
                lb=min(-max(Cxb,[],'all'),-abs(Mt));
                warning('off', 'all');
                if Fn=="Bayesian"
                    str='aV';
                    stg=replace(str,'V',num2str(1));
                    B1='tt-Estimated Best';
                    B2='tt-Best Observed';
                    BE=replace(B1,'tt',kk);
                    BO=replace(B2,'tt',kk);
                    vars(1) = optimizableVariable(stg,[lb(1),ub(1)]);   
                    app.MeanPlot = animatedline(app.UIAxes3, 'Color', 'g','Marker', '^', 'DisplayName', BE);
                    app.GlobalBestPlot = animatedline(app.UIAxes3, 'Color', 'b','Marker', 'o', 'DisplayName', BO);
                    legend(app.UIAxes3, 'show');
                    fun= @(x)Translate_BIM(app,x.a1,Cxb,D_Thresh);
                    results = bayesopt(fun, vars, 'OutputFcn',@(results,state)outputfun_Bayes(app,results,state), 'MaxObjectiveEvaluations', 8*Miter, 'AcquisitionFunctionName', 'expected-improvement-plus', 'IsObjectiveDeterministic', true,'PlotFcn',[],'Verbose',0,'UseParallel',true);
                    % Extract the optimal x value and fitness value
                    xp = results.XAtMinObjective;
                    x = table2array(xp);
                elseif Fn=="Genetic Algorithm"
                    hybridopts = optimoptions('patternsearch','UseParallel',true,'UseCompletePoll',true,'Display', 'off');
                    options = optimoptions('ga','Display','none','FunctionTolerance',1e-10,'OutputFcn',@(options,state,flag)GAOut(app,options,state,flag),'PopulationSize',Gn, 'MaxGenerations',Miter, 'UseParallel', true, 'UseVectorized', false,'HybridFcn',{@patternsearch,hybridopts});
                    B1='tt-Mean Population Score';
                    B2='tt-Best Score';
                    BE=replace(B1,'tt',kk);
                    BO=replace(B2,'tt',kk);
                    app.MeanPlot = animatedline(app.UIAxes3, 'Color', rand(1,3), 'Marker', '^', 'DisplayName', BE);
                    app.GlobalBestPlot = animatedline(app.UIAxes3, 'Color', rand(1,3), 'Marker', 'o', 'DisplayName', BO);
                    legend(app.UIAxes3, 'show');
                    fun= @(x)Translate_BIM(app,x,Cxb,D_Thresh);
                    x = ga(fun,1,[],[],[],[],lb,ub,[],[],options); % Run the genetic algorithm
                elseif Fn=="Particle Swarm"
                    hybridopts = optimoptions('patternsearch','UseParallel',true,'UseCompletePoll',true,'Display', 'off');
                    options = optimoptions('particleswarm','Display','none','FunctionTolerance',1e-10,'OutputFcn',@(optimValues,state)PSOut(app,optimValues,state),'SwarmSize',Gn, 'MaxIterations',Miter, 'UseParallel', true, 'UseVectorized', false,'HybridFcn',{@patternsearch,hybridopts});
                    B1='tt-Mean Particle Score';
                    B2='tt-Best Score';
                    BE=replace(B1,'tt',kk);
                    BO=replace(B2,'tt',kk);
                    app.MeanPlot = animatedline(app.UIAxes3, 'Color', rand(1,3), 'Marker', '^', 'DisplayName', BE);
                    app.GlobalBestPlot = animatedline(app.UIAxes3, 'Color', rand(1,3), 'Marker', 'o', 'DisplayName', BO);
                    legend(app.UIAxes3, 'show');
                    fun= @(x)Translate_BIM(app,x,Cxb,D_Thresh);
                    x = particleswarm(fun,1,lb,ub,options); % Run the particle swarm algorithm
                elseif Fn=="Simulated Annealing"
                    hybridopts = optimoptions('patternsearch','UseParallel',true,'UseCompletePoll',true,'Display', 'off');
                    options = optimoptions('simulannealbnd','Display','none','FunctionTolerance',1e-10,'OutputFcn',@(options,optimvalues,flag)SAOut(app,options,optimvalues,flag),'DisplayInterval',100,'ReannealInterval',Gn,'MaxFunctionEvaluations',Miter*Gn,'HybridFcn',{@patternsearch,hybridopts});
                    B1='tt-Current Score';
                    B2='tt-Best Score';
                    BE=replace(B1,'tt',kk);
                    BO=replace(B2,'tt',kk);
                    app.MeanPlot = animatedline(app.UIAxes3, 'Color', rand(1,3), 'Marker', '^', 'DisplayName', BE);
                    app.GlobalBestPlot = animatedline(app.UIAxes3, 'Color', rand(1,3), 'Marker', 'o', 'DisplayName', BO);
                    legend(app.UIAxes3, 'show');
                    fun= @(x)Translate_BIM(app,x,Cxb,D_Thresh);
                    x = simulannealbnd(fun,0,lb,ub,options); % Run the simulated annealing algorithm
                end
    
            end
        
            function stop = outputfun_Bayes(app,results, state)
                stop = false;
                    switch state
                        case 'initial'
                            % Initialize or reset the plots in the App Designer's axes
                            clearpoints(app.GlobalBestPlot);
                            clearpoints(app.MeanPlot);
                        case 'iteration'
                            % During each iteration, update the plots
                            Ln=floor(length(results.ObjectiveMinimumTrace)/8-1)+1;
                            if Ln>200
                                if std(results.ObjectiveMinimumTrace(end-100+1:end))<=1e-06
                                    stop = true;
                                end
                            end
                            % Calculate the mean of the objective function values observed so far
                           
                            % Get the best (minimum) objective function value found so far
                            % bestObjectiveValue = min(results.ObjectiveTrace);
                            ll=numel(results.ObjectiveTrace)-8;
                            lp=floor(ll/8)+1;
                            bestObjectiveValue = results.ObjectiveMinimumTrace(ll);
                            estObjectiveValue = results.EstimatedObjectiveMinimumTrace(ll);
                            % Update the plots with the latest data
                            addpoints(app.MeanPlot, lp, estObjectiveValue);
                            addpoints(app.GlobalBestPlot, lp, bestObjectiveValue);
                            
                            drawnow;
                        case 'done'
                            % Any cleanup can be performed here
                    end
    
            end
            
            function [state,options,optchanged] = GAOut(app,options,state,flag)
                optchanged = false;
                switch flag
                    case 'init'
                        % Initialize plots: one for the mean score and one for the global best score
                        % on the same axes.
                        clearpoints(app.GlobalBestPlot);
                        clearpoints(app.MeanPlot);
                    case 'iter'
                        % Update the mean score plot
                        currentMeanScore = mean(state.Score);
                        addpoints(app.MeanPlot, state.Generation, currentMeanScore);
                        
                        % Update the global best score plot
                        globalBestScore = min(state.Best); % Assuming state.Best holds the global best
                        addpoints(app.GlobalBestPlot, state.Generation, globalBestScore);
                        
                        % Refresh the plot
                        drawnow;
                    case 'done'
                        % Cleanup code
                end
            end
            function [stop,options,optchanged] = SAOut(app,options,optimvalues,flag)
                stop = false;
                optchanged = false;
                switch flag
                    case 'init'
                        % Initialize plots: one for the mean score and one for the global best score
                        % on the same axes.
                        clearpoints(app.GlobalBestPlot);
                        clearpoints(app.MeanPlot);
                    case 'iter'
                        % Update the mean score plot
                        currentMeanScore = optimvalues.fval;
                        addpoints(app.MeanPlot, optimvalues.iteration, currentMeanScore);
                        % 
                        % Update the global best score plot
                        globalBestScore = optimvalues.bestfval; % Assuming state.Best holds the global best
                        addpoints(app.GlobalBestPlot, optimvalues.iteration, globalBestScore);
                        
                        % Refresh the plot
                        drawnow;
                    case 'done'
                        % Cleanup code
                end
            end
            function stop = PSOut(app,optimValues,state)
                stop = false;
                switch state
                    case 'init'
                        % Initialize plots: one for the mean score and one for the global best score
                        % on the same axes.
                        clearpoints(app.GlobalBestPlot);
                        clearpoints(app.MeanPlot);
                    case 'iter'
                        % Update the mean score plot
                        currentMeanScore = optimValues.meanfval;
                        addpoints(app.MeanPlot, optimValues.iteration, currentMeanScore);
                        
                        % Update the global best score plot
                        globalBestScore = optimValues.bestfval; % Assuming state.Best holds the global best
                        addpoints(app.GlobalBestPlot, optimValues.iteration, globalBestScore);
                        
                        % Refresh the plot
                        drawnow;
                    case 'done'
                        % Cleanup code
                end
            end
            function [fun,M]=Translate_BIM(app,x,Cxb,std)
                C=abs(Cxb-x);
                M = matchpairs(C,std);
                ind=sub2ind([size(C,1),size(C,2)],M(:,1),M(:,2));
                Rs=mean(C(ind));
                fun=-size(M,1)/(1+Rs);
            end
            
            function Tr=Translation_Recover(app,Att_b,Fn,Mt,Pr,dx,D_Thresh,pp,Gn,Miter)
                Tr=zeros(1,3);
                kk=['x','y','z'];
                for k=1:3
                    ib=find(Att_b(:,k)==1);
                    At=-Pr(:,k);
                    xx=min(At)-1:dx:max(At)+1;
                    [f,xi]=sskernel(app,At,xx);
                    % [L,loc]=findpeaks(f);
                    [L,loc] = extrema(app,f);
                    [L1,I1]=sort(L,'descend');
                    i1=min(sum(L1>L1(1)*pp),50);
                    aa=xi(loc(I1(1:i1)))';
                    A_P=aa;
                    Cxb=repmat(A_P,1,length(ib))-repmat(Att_b(ib,4)',length(A_P),1);
                    x=ModelFittingMethod(app,Fn,Cxb,Mt(k),Gn,Miter,D_Thresh,kk(k));
                    [~,mm]=Translate_BIM(app,x,Cxb,D_Thresh);
                    sm=size(mm,1);
                    A_C=zeros(sm,1);
                    for i=1:sm
                        ii=abs(At-A_P(mm(i,1)))<=D_Thresh;
                        [f,xi]=sskernel(app,At(ii));
                        % [L,loc]=findpeaks(f);
                        [L,loc] = extrema(app,f);
                        [~,r]=max(L);
                        A_C(i)=xi(loc(r));
                    end
                    Tr(k)=median(Att_b(ib(mm(:,2)),4)-A_C);
                end
            end
            function [y,t,optw,W,C,confb95,yb] = sskernel(app,x,tin,W)
                % Copyright (c) 2010, Hideaki Shimazaki
                % All rights reserved.
                % 
                % Redistribution and use in source and binary forms, with or without
                % modification, are permitted provided that the following conditions are met:
                % 
                % * Redistributions of source code must retain the above copyright notice, this
                %   list of conditions and the following disclaimer.
                % 
                % * Redistributions in binary form must reproduce the above copyright notice,
                %   this list of conditions and the following disclaimer in the documentation
                %   and/or other materials provided with the distribution
                % THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS IS"
                % AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED TO, THE
                % IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE ARE
                % DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT OWNER OR CONTRIBUTORS BE LIABLE
                % FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL, EXEMPLARY, OR CONSEQUENTIAL
                % DAMAGES (INCLUDING, BUT NOT LIMITED TO, PROCUREMENT OF SUBSTITUTE GOODS OR
                % SERVICES; LOSS OF USE, DATA, OR PROFITS; OR BUSINESS INTERRUPTION) HOWEVER
                % CAUSED AND ON ANY THEORY OF LIABILITY, WHETHER IN CONTRACT, STRICT LIABILITY,
                % OR TORT (INCLUDING NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE
                % OF THIS SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.
                % Modified From: Hideaki Shimazaki (2025). sskernel(x,tin,W) 
                % (https://www.mathworks.com/matlabcentral/fileexchange/24959-sskernel-x-tin-w), 
                % MATLAB Central File Exchange. Retrieved March 11, 2025.
                x = reshape(x,1,numel(x));
                if nargin == 2
                    T = max(x) - min(x);
                    [mbuf,nbuf,dt_samp] = find( sort(diff(sort(x))),1,'first');
                    tin = linspace(min(x),max(x), min(ceil(T/dt_samp),1e3));
                    t = tin;
                    x_ab = x( logical((x >= min(tin)) .*(x <= max(tin))) ) ;
                else
                    T = max(tin) - min(tin);    
                    x_ab = x( logical((x >= min(tin)) .*(x <= max(tin))) ) ;
                    [mbuf,nbuf,dt_samp] = find( sort(diff(sort(x_ab))),1,'first');
                    if dt_samp > min(diff(tin))
                        t = linspace(min(tin),max(tin), min(ceil(T/dt_samp),1e3));
                    else
                        t = tin;
                    end
                end
                dt = min(diff(t));
                % Create a finest histogram
                y_hist = histc(x_ab,t-dt/2);
                L = length(y_hist);
                N = sum(y_hist);
                y_hist = y_hist/N/dt;   %density
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Compute a Cost Function
                if nargin >= 4
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Global search
                C = zeros(1,length(W));
                C_min = Inf;
                for k = 1: length(W)
	                w = W(k);     
                    [C(k) yh] = CostFunction(app,y_hist,N,w,dt);
                    
                    if C(k) < C_min
                        C_min = C(k);
                        optw = w;
                        y = yh;
                    end
                end
                else
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Golden section search on a log-exp scale
                % Initialize
                Wmin = 2*dt; Wmax = 1*(max(x) - min(x));
                tol = 10^-6; 
                phi = (sqrt(5) + 1)/2;        %golden ratio
                %logexp = @(x) log(1+exp(x));
                %ilogexp = @(x) log(exp(x)-1);
                %a = Wmin; b = Wmax;
                a = ilogexp(app,Wmin); b = ilogexp(app,Wmax);
                c1 = (phi-1)*a + (2-phi)*b;
                c2 = (2-phi)*a + (phi-1)*b;
                f1 = CostFunction(app,y_hist,N,logexp(app,c1),dt);
                f2 = CostFunction(app,y_hist,N,logexp(app,c2),dt);
                k = 1;
                while abs(b-a) > tol*(abs(c1)+abs(c2)) && k <= 1000
	                if (f1 < f2)    
                        b = c2;
                        c2 = c1;
                        c1 = (phi - 1)*a + (2 - phi)*b;
                        
                        f2 = f1;
                        [f1 yh1] = CostFunction(app,y_hist,N,logexp(app,c1),dt);
                        
                        W(k) = logexp(app,c1);
                        C(k) = f1;
                        optw = logexp(app,c1);
                        y = yh1./sum(yh1.*dt);  %make the final output a density
                    else
                        a = c1;
                        c1 = c2;
                        
                        c2 = (2 - phi)*a + (phi - 1)*b;
                        
                        f1 = f2;
                        [f2 yh2] = CostFunction(app,y_hist,N,logexp(app,c2),dt);
                        
                        W(k) = logexp(app,c2);
                        C(k) = f2;
                        optw = logexp(app,c2);
                        y = yh2./sum(yh2.*dt);
                    end
                    
                    k = k + 1;
                end
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Bootstrap Confidence Intervals
                if nargout == 0 || nargout >= 6
                    nbs = 1e3;        %number of bootstrap samples
                    yb = zeros(nbs,length(tin));
                    for i = 1: nbs,
                        %y_histb = poissrnd(y_hist*dt*N)/dt/N;
                    
                        idx = ceil(rand(1,N)*N);
                        xb = x_ab(idx);
                        y_histb = histc(xb,t-dt/2)/dt/N;
                    
                        yb_buf = fftkernel(app,y_histb,optw/dt);
                        yb_buf = yb_buf / sum(yb_buf*dt);
                        
                        yb(i,:) = interp1(t,yb_buf,tin);
                    end
                    ybsort = sort(yb);
                    y95b = ybsort(floor(0.05*nbs),:);
                    y95u = ybsort(floor(0.95*nbs),:);
                    
                    confb95 = [y95b; y95u];
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Return results
                y = interp1(t,y,tin);
                t = tin;
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Display results
                if nargout == 0
                            hold on;
                            line([t; t],[y95b; y95u]...
                                    ,'Color',[7 7 7]/8,'LineWidth',1 );
                            plot(t,y95b,'Color',[7 7 7]/9,'LineWidth',1);
                            plot(t,y95u,'Color',[7 7 7]/9,'LineWidth',1);
                            plot(t,y,'Color',[0.9 0.2 0.2],'LineWidth',2);
                            grid on;
                            ylabel('density');
                            set(gca,'TickDir','out');  
                else
                    if nargin >= 5
                        if strcmp(option,'Visible')
                            hold on; 
                            
                            if nargout >= 6
                                line([t; t],[y95b; y95u]...
                                    ,'Color',[7 7 7]/8,'LineWidth',1 );
                                plot(t,y95b,'Color',[7 7 7]/9,'LineWidth',1);
                                plot(t,y95u,'Color',[7 7 7]/9,'LineWidth',1);
                            end
                            plot(t,y,'Color',[0.9 0.2 0.2],'LineWidth',1);
                            grid on;
                            ylabel('density');
                            set(gca,'TickDir','out'); 
                            
                        end
                    end
                end
            end
            function [C yh] = CostFunction(app,y_hist,N,w,dt)
                % Copyright (c) 2010, Hideaki Shimazaki
                % All rights reserved.
                % 
                % Redistribution and use in source and binary forms, with or without
                % modification, are permitted provided that the following conditions are met:
                % 
                % * Redistributions of source code must retain the above copyright notice, this
                %   list of conditions and the following disclaimer.
                % 
                % * Redistributions in binary form must reproduce the above copyright notice,
                %   this list of conditions and the following disclaimer in the documentation
                %   and/or other materials provided with the distribution
                % THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS IS"
                % AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED TO, THE
                % IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE ARE
                % DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT OWNER OR CONTRIBUTORS BE LIABLE
                % FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL, EXEMPLARY, OR CONSEQUENTIAL
                % DAMAGES (INCLUDING, BUT NOT LIMITED TO, PROCUREMENT OF SUBSTITUTE GOODS OR
                % SERVICES; LOSS OF USE, DATA, OR PROFITS; OR BUSINESS INTERRUPTION) HOWEVER
                % CAUSED AND ON ANY THEORY OF LIABILITY, WHETHER IN CONTRACT, STRICT LIABILITY,
                % OR TORT (INCLUDING NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE
                % OF THIS SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.
                % Modified From: Hideaki Shimazaki (2025). sskernel(x,tin,W) 
                % (https://www.mathworks.com/matlabcentral/fileexchange/24959-sskernel-x-tin-w), 
                % MATLAB Central File Exchange. Retrieved March 11, 2025.
                yh = fftkernel(app,y_hist,w/dt);  %density
                %formula for density
                C = sum(yh.^2)*dt - 2* sum(yh.*y_hist)*dt...
                        + 2*1/sqrt(2*pi)/w/N; 
                C = C * N* N;
            end 
            function y = fftkernel(app,x,w)
                % Copyright (c) 2010, Hideaki Shimazaki
                % All rights reserved.
                % 
                % Redistribution and use in source and binary forms, with or without
                % modification, are permitted provided that the following conditions are met:
                % 
                % * Redistributions of source code must retain the above copyright notice, this
                %   list of conditions and the following disclaimer.
                % 
                % * Redistributions in binary form must reproduce the above copyright notice,
                %   this list of conditions and the following disclaimer in the documentation
                %   and/or other materials provided with the distribution
                % THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS IS"
                % AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED TO, THE
                % IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE ARE
                % DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT OWNER OR CONTRIBUTORS BE LIABLE
                % FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL, EXEMPLARY, OR CONSEQUENTIAL
                % DAMAGES (INCLUDING, BUT NOT LIMITED TO, PROCUREMENT OF SUBSTITUTE GOODS OR
                % SERVICES; LOSS OF USE, DATA, OR PROFITS; OR BUSINESS INTERRUPTION) HOWEVER
                % CAUSED AND ON ANY THEORY OF LIABILITY, WHETHER IN CONTRACT, STRICT LIABILITY,
                % OR TORT (INCLUDING NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE
                % OF THIS SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.
                % Modified From: Hideaki Shimazaki (2025). sskernel(x,tin,W) 
                % (https://www.mathworks.com/matlabcentral/fileexchange/24959-sskernel-x-tin-w), 
                % MATLAB Central File Exchange. Retrieved March 11, 2025.
                L = length(x);
                Lmax = max(1:L+3*w);
                n = 2^(nextpow2(Lmax));
                X = fft(x,n);
                f = (0:n-1)/n;
                f = [-f(1:n/2+1) f(n/2:-1:2)];
                K = exp(-0.5*(w*2*pi*f).^2);
                y = ifft(X.*K,n);
                y = y(1:L);
            end
            function y = logexp(app,x)
                % Copyright (c) 2010, Hideaki Shimazaki
                % All rights reserved.
                % 
                % Redistribution and use in source and binary forms, with or without
                % modification, are permitted provided that the following conditions are met:
                % 
                % * Redistributions of source code must retain the above copyright notice, this
                %   list of conditions and the following disclaimer.
                % 
                % * Redistributions in binary form must reproduce the above copyright notice,
                %   this list of conditions and the following disclaimer in the documentation
                %   and/or other materials provided with the distribution
                % THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS IS"
                % AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED TO, THE
                % IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE ARE
                % DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT OWNER OR CONTRIBUTORS BE LIABLE
                % FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL, EXEMPLARY, OR CONSEQUENTIAL
                % DAMAGES (INCLUDING, BUT NOT LIMITED TO, PROCUREMENT OF SUBSTITUTE GOODS OR
                % SERVICES; LOSS OF USE, DATA, OR PROFITS; OR BUSINESS INTERRUPTION) HOWEVER
                % CAUSED AND ON ANY THEORY OF LIABILITY, WHETHER IN CONTRACT, STRICT LIABILITY,
                % OR TORT (INCLUDING NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE
                % OF THIS SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.
                % Modified From: Hideaki Shimazaki (2025). sskernel(x,tin,W) 
                % (https://www.mathworks.com/matlabcentral/fileexchange/24959-sskernel-x-tin-w), 
                % MATLAB Central File Exchange. Retrieved March 11, 2025.
                idx = x<1e2;  
                y(idx) = log(1+exp(x(idx)));
                idx = x>=1e2; 
                y(idx) = x(idx);
            end
            function y = ilogexp(app,x)
                % Copyright (c) 2010, Hideaki Shimazaki
                % All rights reserved.
                % 
                % Redistribution and use in source and binary forms, with or without
                % modification, are permitted provided that the following conditions are met:
                % 
                % * Redistributions of source code must retain the above copyright notice, this
                %   list of conditions and the following disclaimer.
                % 
                % * Redistributions in binary form must reproduce the above copyright notice,
                %   this list of conditions and the following disclaimer in the documentation
                %   and/or other materials provided with the distribution
                % THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS IS"
                % AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED TO, THE
                % IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE ARE
                % DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT OWNER OR CONTRIBUTORS BE LIABLE
                % FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL, EXEMPLARY, OR CONSEQUENTIAL
                % DAMAGES (INCLUDING, BUT NOT LIMITED TO, PROCUREMENT OF SUBSTITUTE GOODS OR
                % SERVICES; LOSS OF USE, DATA, OR PROFITS; OR BUSINESS INTERRUPTION) HOWEVER
                % CAUSED AND ON ANY THEORY OF LIABILITY, WHETHER IN CONTRACT, STRICT LIABILITY,
                % OR TORT (INCLUDING NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE
                % OF THIS SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.
                % Modified From: Hideaki Shimazaki (2025). sskernel(x,tin,W) 
                % (https://www.mathworks.com/matlabcentral/fileexchange/24959-sskernel-x-tin-w), 
                % MATLAB Central File Exchange. Retrieved March 11, 2025.
                %ilogexp = @(x) log(exp(x)-1);
                idx = x<1e2;  
                y(idx) = log(exp(x(idx))-1);
                idx = x>=1e2; 
                y(idx) = x(idx);
            end

        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            for i=1:size(app.Bounds,1)
                xx=reshape(app.Bounds(i,:),4,3);
                patch(app.UIAxes,xx(:,1),xx(:,2),xx(:,3),[0 0.4470 0.7410],'FaceAlpha',0.5)
                view(app.UIAxes, 30, 20);
                axis(app.UIAxes, 'equal');
            end
        end

        % Button pushed function: ApplyTransformationButton
        function ApplyTransformationButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            app.Lamp.Color = [1 1 1];
            for i=1:size(app.Bounds,1)
                xx=reshape(app.Bounds(i,:),4,3);
                patch(app.UIAxes,xx(:,1),xx(:,2),xx(:,3),[0 0.4470 0.7410],'FaceAlpha',0.5)
                view(app.UIAxes, 30, 20);
                axis(app.UIAxes, 'equal');
            end
            sig=app.RandomErrormmSlider.Value/1000;
            tet=app.RotationAngleSlider.Value*pi/180;
            app.Tf=[app.TranslationXmSlider.Value,app.TranslationYmSlider.Value,app.TranslationZmSlider.Value];
            app.R=[cos(tet),sin(tet),0;-sin(tet),cos(tet),0;0,0,1];
            lp=length(app.XP);
            app.P=(app.XP(:,1:3)+sig*randn(lp,3))*app.R+app.Tf;
            colors = hsv2rgb([abs(app.P(:,3)./max(abs(app.P(:,3)))), ones(lp, 1), ones(lp, 1)]);
            hold(app.UIAxes, 'on');
            scatter3(app.UIAxes, app.P(:,1), app.P(:,2), app.P(:,3), 10, colors, 'filled');
            % plot3(app.UIAxes, app.P(:,1), app.P(:,2), app.P(:,3), colors, 'filled','-o','MarkerSize',10)
            hold(app.UIAxes, 'off');
            % Set axes properties
            colormap(app.UIAxes, 'hsv'); % Apply HSV colormap
            app.Lamp.Color = [0 1 0];
        end

        % Button pushed function: EstimateNormalButton
        function EstimateNormalButtonPushed(app, event)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            app.Lamp_2.Color = [1 1 0];
            Fn=app.SupervisedRegressionDropDown.Value;
            sig=app.RandomErrormmSlider.Value/1000;
            Neighb=app.NeighbourhoodSizeSlider.Value/1000;
            ii=randperm(length(app.D_Var),500);
            dv=app.D_Var(ii,:);
            dr=app.R_Var(ii,:);
            n=250;
            N=EstimateNormal(app,Fn,app.XP(:,4:6),app.R,dv,dr,sig,Neighb);
            histogram2(app.UIAxes2,N(:,1),N(:,2),n)
            [app.N_P,app.FX]=Significant_Normals(app,N,n);
            hold(app.UIAxes2, 'on');
            scatter3(app.UIAxes2, app.FX(:,1), app.FX(:,2), app.FX(:,3), 30,'g','filled');
            hold(app.UIAxes2, 'off');
            app.Lamp_2.Color = [0 1 0];
        end

        % Button pushed function: RecoverRotationButton
        function RecoverRotationButtonPushed(app, event)
            cla(app.UIAxes3)
            app.Lamp_3.Color = [1 1 0];
            [x,Rr,Rot_err]=Rotation_Prediction(app,app.N_P,app.R,3,app.FX/max(app.FX));
            hold(app.UIAxes2, 'on');
            scatter3(app.UIAxes2, app.FX(x==1,1), app.FX(x==1,2), app.FX(x==1,3), 30,'r','filled');
            hold(app.UIAxes2, 'off');
            app.P=app.P*Rr';
            app.Tf=app.Tf*Rr';
            colors = hsv2rgb([abs(app.P(:,3)./max(abs(app.P(:,3)))), ones(length(app.P), 1), ones(length(app.P), 1)]);
            scatter3(app.UIAxes, app.P(:,1), app.P(:,2), app.P(:,3), 10, colors, 'filled');
            colormap(app.UIAxes, 'hsv'); % Apply HSV colormap
            for i=1:size(app.Bounds,1)
                xx=reshape(app.Bounds(i,:),4,3);
                patch(app.UIAxes,xx(:,1),xx(:,2),xx(:,3),[0 0.4470 0.7410],'FaceAlpha',0.5)
                view(app.UIAxes, 30, 20);
                axis(app.UIAxes, 'equal');
            end
            
            % Set axes properties
            
            app.RotationErrorEditField.Value=Rot_err;
            app.Lamp_3.Color = [0 1 0];
        end

        % Button pushed function: RecoverTranslationButton
        function RecoverTranslationButtonPushed(app, event)
            cla(app.UIAxes3)
            app.Lamp_4.Color = [1 1 0];
            % Initializations
            dx=0.02;
            D_Thresh=0.05;
            pp=0.1; Miter=1000;
            % App allocation
            Gn=ceil(app.TuningParameterSlider.Value);         
            Fn=app.AIOptimizationMethodDropDown.Value;
            Tr=Translation_Recover(app,app.Att_BIM,Fn,app.Tf*1.5,app.P,dx,D_Thresh,pp,Gn,Miter);
            Trans_Err=sqrt(mean((Tr-app.Tf).^2));
            app.Lamp_4.Color = [0 1 0];
            app.TranslationErrormEditField.Value=Trans_Err;
            app.P=app.P-Tr;
            colors = hsv2rgb([abs(app.P(:,3)./max(abs(app.P(:,3)))), ones(length(app.P), 1), ones(length(app.P), 1)]);
            scatter3(app.UIAxes, app.P(:,1), app.P(:,2), app.P(:,3), 10, colors, 'filled');
            colormap(app.UIAxes, 'hsv'); % Apply HSV colormap
            for i=1:size(app.Bounds,1)
                xx=reshape(app.Bounds(i,:),4,3);
                patch(app.UIAxes,xx(:,1),xx(:,2),xx(:,3),[0 0.4470 0.7410],'FaceAlpha',0.5)
                view(app.UIAxes, 30, 20);
                axis(app.UIAxes, 'equal');
            end
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            view(app.UIAxes, 30, 20);
        end

        % Button pushed function: yzButton
        function yzButtonPushed(app, event)
            view(app.UIAxes, 90, 0);
        end

        % Button pushed function: xyButton
        function xyButtonPushed(app, event)
            view(app.UIAxes, 2);
        end

        % Button pushed function: xzButton
        function xzButtonPushed(app, event)
            view(app.UIAxes, 0, 0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'AI-based Translation Recovery')
            xlabel(app.UIAxes3, 'Iteration')
            ylabel(app.UIAxes3, 'Objective Function')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Normal Estimation')
            xlabel(app.UIAxes2, 'N_x')
            ylabel(app.UIAxes2, 'N_y')
            zlabel(app.UIAxes2, 'Frequency (#)')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'BIM and Point Cloud')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z (m)')
            app.ApplyTransformationButton = uibutton(app.GridLayout, 'push');
            app.ApplyTransformationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyTransformationButtonPushed, true);
            app.ApplyTransformationButton.Text = 'Apply Transformation';
            app.RotationAngleLabel = uilabel(app.GridLayout);
            app.RotationAngleLabel.Text = {'Rotation '; 'Angle (°)'};
            app.RotationAngleSlider = uislider(app.GridLayout);
            app.RotationAngleSlider.Limits = [0 60];
            app.TranslationXmLabel = uilabel(app.GridLayout);
            app.TranslationXmLabel.Text = {'Translation '; 'X (m)'};
            app.TranslationXmSlider = uislider(app.GridLayout);
            app.TranslationXmSlider.Limits = [-20 20];
            app.TranslationYmLabel = uilabel(app.GridLayout);
            app.TranslationYmLabel.Text = {'Translation '; 'Y (m)'};
            app.TranslationYmSlider = uislider(app.GridLayout);
            app.TranslationYmSlider.Limits = [-20 20];
            app.TranslationZmLabel = uilabel(app.GridLayout);
            app.TranslationZmLabel.Text = {'Translation '; 'Z (m)'};
            app.TranslationZmSlider = uislider(app.GridLayout);
            app.TranslationZmSlider.Limits = [-5 5];
            app.SupervisedRegressionDropDownLabel = uilabel(app.GridLayout);
            app.SupervisedRegressionDropDownLabel.Text = 'Supervised Regression';
            app.SupervisedRegressionDropDown = uidropdown(app.GridLayout);
            app.SupervisedRegressionDropDown.Items = {'Gaussian Regression', 'SVM Cubic', 'Kernel'};
            app.SupervisedRegressionDropDown.Value = 'Gaussian Regression';
            app.EstimateNormalButton = uibutton(app.GridLayout, 'push');
            app.EstimateNormalButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateNormalButtonPushed, true);
            app.EstimateNormalButton.Text = 'Estimate Normal';
            app.RecoverRotationButton = uibutton(app.GridLayout, 'push');
            app.RecoverRotationButton.ButtonPushedFcn = createCallbackFcn(app, @RecoverRotationButtonPushed, true);
            app.RecoverRotationButton.Text = 'Recover Rotation';
            app.AIOptimizationMethodDropDownLabel = uilabel(app.GridLayout);
            app.AIOptimizationMethodDropDownLabel.Text = 'AI Optimization Method';
            app.AIOptimizationMethodDropDown = uidropdown(app.GridLayout);
            app.AIOptimizationMethodDropDown.Items = {'Particle Swarm', 'Genetic Algorithm', 'Simulated Annealing'};
            app.AIOptimizationMethodDropDown.Value = 'Particle Swarm';
            app.RotationErrorLabel = uilabel(app.GridLayout);
            app.RotationErrorLabel.Text = {'Rotation '; 'Error (°)'};
            app.RotationErrorEditField = uieditfield(app.GridLayout, 'numeric');
            app.TranslationErrormLabel = uilabel(app.GridLayout);
            app.TranslationErrormLabel.Text = {'Translation '; 'Error (m)'};
            app.TranslationErrormEditField = uieditfield(app.GridLayout, 'numeric');
            app.RecoverTranslationButton = uibutton(app.GridLayout, 'push');
            app.RecoverTranslationButton.ButtonPushedFcn = createCallbackFcn(app, @RecoverTranslationButtonPushed, true);
            app.RecoverTranslationButton.Text = 'Recover Translation';
            app.Lamp = uilamp(app.GridLayout);
            app.Lamp_2 = uilamp(app.GridLayout);
            app.Lamp_3 = uilamp(app.GridLayout);
            app.Lamp_4 = uilamp(app.GridLayout);
            app.TransformationAppliedLabel = uilabel(app.GridLayout);
            app.TransformationAppliedLabel.Text = 'Transformation Applied';
            app.NormalEstimatedLabel = uilabel(app.GridLayout);
            app.NormalEstimatedLabel.Text = 'Normal Estimated';
            app.RandomErrormmLabel = uilabel(app.GridLayout);
            app.RandomErrormmLabel.Text = {'Random '; 'Error (mm)'};
            app.RandomErrormmSlider = uislider(app.GridLayout);
            app.RandomErrormmSlider.Limits = [3 8];
            app.RandomErrormmSlider.Value = 3;
            app.NeighbourhoodSizeSliderLabel = uilabel(app.GridLayout);
            app.NeighbourhoodSizeSliderLabel.Text = 'Neighbourhood Size (#)';
            app.NeighbourhoodSizeSlider = uislider(app.GridLayout);
            app.NeighbourhoodSizeSlider.Limits = [250 500];
            app.NeighbourhoodSizeSlider.Value = 400;
            app.TuningParameterSliderLabel = uilabel(app.GridLayout);
            app.TuningParameterSliderLabel.Text = 'Tuning Parameter';
            app.TuningParameterSlider = uislider(app.GridLayout);
            app.TuningParameterSlider.Limits = [50 500];
            app.TuningParameterSlider.Value = 100;
            app.ViewPanel = uipanel(app.GridLayout);
            app.ViewPanel.Title = 'View';
            app.DefaultButton = uibutton(app.ViewPanel, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.xyButton = uibutton(app.ViewPanel, 'push');
            app.xyButton.ButtonPushedFcn = createCallbackFcn(app, @xyButtonPushed, true);
            app.xyButton.Text = 'x-y';
            app.xzButton = uibutton(app.ViewPanel, 'push');
            app.xzButton.ButtonPushedFcn = createCallbackFcn(app, @xzButtonPushed, true);
            app.xzButton.Text = 'x-z';
            app.yzButton = uibutton(app.ViewPanel, 'push');
            app.yzButton.ButtonPushedFcn = createCallbackFcn(app, @yzButtonPushed, true);
            app.yzButton.Text = 'y-z';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FIM_Rectilinear_Transformation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end