classdef Point_Cloud_FIM_Planar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        DetectAllBIMElementsButton    matlab.ui.control.Button
        ShowPointCloudSwitch          matlab.ui.control.Switch
        ShowPointCloudSwitchLabel     matlab.ui.control.Label
        DefaultButton_2               matlab.ui.control.Button
        yzButton_2                    matlab.ui.control.Button
        xzButton_2                    matlab.ui.control.Button
        xyButton_2                    matlab.ui.control.Button
        BIMElementIDSpinner           matlab.ui.control.Spinner
        BIMElementIDSpinnerLabel      matlab.ui.control.Label
        ColourMapPanel                matlab.ui.container.Panel
        TotalErrorButton              matlab.ui.control.Button
        DetectionButton               matlab.ui.control.Button
        CoverageButton                matlab.ui.control.Button
        AccuracyButton                matlab.ui.control.Button
        DetectSingleBIMElementButton  matlab.ui.control.Button
        AngularThresholdcircSlider    matlab.ui.control.Slider
        AngularThresholdcircSliderLabel  matlab.ui.control.Label
        CoverageThresholdSlider       matlab.ui.control.Slider
        AcceptableCoverageLabel       matlab.ui.control.Label
        MeasurementErrormmSlider      matlab.ui.control.Slider
        MeasurementErrormmLabel       matlab.ui.control.Label
        ViewPanel                     matlab.ui.container.Panel
        yzButton                      matlab.ui.control.Button
        xzButton                      matlab.ui.control.Button
        xyButton                      matlab.ui.control.Button
        DefaultButton                 matlab.ui.control.Button
        BIMDetectionCompletedLabel    matlab.ui.control.Label
        PointCloudGeneratedLabel      matlab.ui.control.Label
        Lamp_4                        matlab.ui.control.Lamp
        Lamp_3                        matlab.ui.control.Lamp
        Lamp_2                        matlab.ui.control.Lamp
        Lamp                          matlab.ui.control.Lamp
        TotalErrorcmEditField         matlab.ui.control.NumericEditField
        TotalErrorcmEditFieldLabel    matlab.ui.control.Label
        FinalRMSEcmEditField          matlab.ui.control.NumericEditField
        FinalRMSEcmEditFieldLabel     matlab.ui.control.Label
        DistanceThresholdcmSlider     matlab.ui.control.Slider
        Distance_ThreshcmLabel        matlab.ui.control.Label
        RMSEThresholdmumSlider        matlab.ui.control.Slider
        RMSE_ThreshmmLabel            matlab.ui.control.Label
        PointDensityMultiplierSlider  matlab.ui.control.Slider
        RotationAngleConstructionErrorLabel  matlab.ui.control.Label
        GeneratePointCloudButton      matlab.ui.control.Button
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
B_P=[32.3611849297425	0.0310486045906910	0.0173763853066369	0.0308233479637864	0.119202044766790	0.132357989494288	1
32.3711689762877	0.0313515144520680	0.107197649271239	0.0308233479637864	0.119202044766790	0.132357989494288	1
32.3711672037321	0.0313848996269639	0.117177593273495	0.0308233479637864	0.119202044766790	0.132357989494288	1
32.3711654311766	0.0314182848018589	0.127157537275749	0.0308233479637864	0.119202044766790	0.132357989494288	1
32.3711618860655	0.0314850551516497	0.147117425280258	0.0308233479637864	0.119202044766790	0.132357989494288	1
32.3711601135099	0.0315184403265445	0.157097369282513	0.0308233479637864	0.119202044766790	0.132357989494288	1
32.3711565683988	0.0315852106763364	0.177057257287024	0.0308233479637864	0.119202044766790	0.132357989494288	1
32.3611352981869	0.0319833894877606	0.296814817369774	0.0308233479637864	0.119202044766790	0.132357989494288	1
32.3611335256313	0.0320167746626572	0.306794761372030	0.0308233479637864	0.119202044766790	0.132357989494288	1
32.3611317530758	0.0320501598375522	0.316774705374284	0.0308233479637864	0.119202044766790	0.132357989494288	1
32.3611299805202	0.0320835450124481	0.326754649376540	0.0308233479637864	0.119202044766790	0.132357989494288	1
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8.38769815984294	0.644177392595463	0.00408959016270871	0.990053331105824	0.620982833613232	0.839838497136910	71];
        At_P=[0.000485276655234133	0.999995922446451	-0.00281417786217933	-0.0466240987149072
0.999996105946692	0.000969388628457080	-0.00261694041581460	-29.4244272745367
0.999979886688735	0.00633330877522420	0.000339732162658001	-8.58881587645346
0.00607493255449565	0.999964243298566	-0.00588279855048250	-9.05622686411764
0.999988719697089	-0.00200632265509673	0.00430524656454106	-3.06702353443789
-0.999980672733823	-0.00584431260789120	0.00212088871775005	20.2759193313350
-0.00434630607535289	0.999987203838765	0.00258878006524489	-9.38584273020128
0.999991942072233	-0.00395806389641583	0.000670463119872997	-14.2295873427792
-0.000683066581943902	-0.000999582910746051	0.999999267126756	0.193542376563471
-0.999973016438764	0.00710063914069437	-0.00188343254555893	14.4785288100230
0.00203458940550195	0.999968320633838	-0.00769533460571053	-13.8504638194947
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-0.00678394279622047	-0.999974518516102	-0.00222270974905507	0.704392407943605];
        m_P=[30.9162525616232	0.0358781037219869	1.51262448791075
29.4255077633924	2.78188320070255	1.39958490385604
8.58635752466157	0.344225542214709	1.32741399202056
32.4729097836810	8.86854420139850	1.57594452677922
3.08451226274382	11.4017203050613	1.25929969412123
20.2255469458397	9.16916506201521	1.33160072354257
20.4041256452400	9.47117775223142	1.33991604315895
14.2300802198150	0.329568614063351	1.38149516063715
19.3442893091213	4.98647605662261	-0.175344671239330
14.5764788782139	14.1003833805382	1.36187051463767
26.4253680320896	13.8076203663493	1.36237143217290
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8.42998229558801	0.643967283802521	1.46353599160651];
        
        B_B=[29.4036998748779	0.200000002980232	0	1	1
29.4036998748779	5.41219997406006	0	1	1
29.4036998748779	5.41219997406006	2.61999988555908	1	1
29.4036998748779	0.200000002980232	2.61999988555908	1	1
29.6037006378174	5.41219997406006	2.61999988555908	1	2
29.6037006378174	5.41219997406006	0	1	2
29.6037006378174	0.200000002980232	0	1	2
29.6037006378174	0.200000002980232	2.61999988555908	1	2
29.4036998748779	0.200000002980232	0	1	3
29.4036998748779	5.41219997406006	0	1	3
29.6037006378174	5.41219997406006	0	1	3
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14.5490999221802	8.57999992370606	0	30	6];
        m_B=[29.4036998748779	2.80609998852015	1.30999994277954	1	1
29.6037006378174	2.80609998852015	1.30999994277954	1	2
29.5037002563477	2.80609998852015	0	1	3
29.5037002563477	0.200000002980232	1.30999994277954	1	4
29.5037002563477	5.41219997406006	1.30999994277954	1	5
29.5037002563477	2.80609998852015	2.61999988555908	1	6
30.9018507003784	5.61219978332520	1.30999994277954	2	1
30.9018507003784	5.41219997406006	1.30999994277954	2	2
30.9018507003784	5.51219987869263	-1.00849753060491e-24	2	3
32.4000015258789	5.51219987869263	1.30999994277954	2	4
29.4036998748779	5.51219987869263	1.30999994277954	2	5
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14.5490999221802	8.88000011444092	1.30999994277954	30	6];
        At_B=[1	0	0	-29.4036998748779	1	1
1	0	0	-29.6037006378174	1	2
0	0	1	0	1	3
0	1	0	-0.200000002980232	1	4
0	1	0	-5.41219997406006	1	5
0	0	1	-2.61999988555908	1	6
0	1	0	-5.61219978332520	2	1
0	1	0	-5.41219997406006	2	2
0	2.01699698476804e-23	1	-1.13197900408828e-22	2	3
1	0	0	-32.4000015258789	2	4
1	0	0	-29.4036998748779	2	5
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1	0	0	-14.5490999221802	30	6];
        Ar_B=[13.6559633277402	1	1
13.6559633277402	1	2
1.04244397080896	1	3
0.524001976013096	1	4
0.524001976013096	1	5
1.04244397080896	1	6
7.85030998272305	2	1
7.85030998272305	2	2
0.599259758701010	2	3
0.523999477386496	2	4
0.523999477386496	2	5
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1.57200093078609	30	6];
        % Description
        X_P % Description
        Bound_P % Description
        axB % Description
        axP
        axpt
        RMSELine % Description
        Cloud % Description
        Property=zeros(30,5); % Description
        Assign=zeros(30,71); % Description
        ColorBar % Description
    end
    
    methods (Access = private)
        
        function [Assign,Properties,All_Assign,All_Coverage,All_Accuracy,All_ConstErr,Algorithm]=Assignment_Plane_Plane_FIM(app,Bounds_BIM,BIM_mean,Area_BIM,Att_BIM,Att_P,Bounds_P,Planar,P_mean,Area_Th,Dir_Th,Dis_Th,RMSE_Th,maxIter)
            np=size(Att_P,1);
            nb=size(Att_BIM,1);   
            Assign=zeros(1,np);
            Err=Inf(1,maxIter);
            k=1; %Start the ID of iteration from 1
            il=0;
            Rot=eye(3);
            TR=zeros(1,3);
            as=zeros(nb,np);
            tic;
            app.RMSELine = animatedline(app.UIAxes3, 'Marker', 'o', 'LineStyle', '-');
            while il==0
                ATP(:,1:3)=Att_P(:,1:3)*Rot;
                ATP(:,4)=-sum(ATP(:,1:3).*(P_mean*Rot+TR),2);
                BPs=cell(np,1);
                for tt=1:np
                    BPs{tt,1}=Bounds_P{tt,1}(:,1:3)*Rot+TR;
                end
                
                [ID_BIM,Ar_Sim]=BIM_Planar_Correspondance_FIM(app,Bounds_BIM,BIM_mean,Area_BIM,Att_BIM,ATP,BPs,Area_Th,Dir_Th,Dis_Th);
                I_B=find(ID_BIM>0);

                if ~isempty(I_B)
                    I_P=ID_BIM(I_B);
                    Pl_P=[];
                    BIM_P=[];
                    ll=zeros(length(I_B),1);
                    for j=1:length(I_B)
                        lp=length(Planar{I_P(j),1}(:,1));
                        Pls=Planar{I_P(j),1}(:,1:3)*Rot+TR;
                        Atb=Att_BIM(I_B(j),:);
                        ds=[Pls,ones(lp,1)]*Atb';
                        Cs=Pls-ds.*Atb(1:3);
                        Rt=Rodrigues(app,Atb(1:3),[0,0,1]);
                        Proj=Bounds_BIM{I_B(j),1}*Rt;
                        Csr=Cs*Rt;
                        pgonP = polyshape(Proj(:,1),Proj(:,2));
                        in = isinterior(pgonP,Csr(:,1:2));
                        BIM_P=[BIM_P;Cs(in,:)];
                        Pl_P=[Pl_P;Pls(in,:)];
                        ll(j)=sum(in);
                    end
                    
                    if ~isempty(app.axB) && isvalid(app.axB)
                        delete(app.axB);
                    end
                    if ~isempty(app.axP) && isvalid(app.axP)
                        delete(app.axP);
                    end
                    if ~isempty(app.axpt) && isvalid(app.axpt)
                        delete(app.axpt);
                    end

                    for i=1:size(I_B,1)
                        xx=Bounds_BIM{I_B(i),1}(:,1:3);
                        app.axB=patch(app.UIAxes2,xx(:,1),xx(:,2),xx(:,3),'g','FaceAlpha',0.5); %% Bolden the patch lines
                    end
                        hold(app.UIAxes2, 'on');
                        app.axP=scatter3(app.UIAxes2, Pl_P(:,1), Pl_P(:,2), Pl_P(:,3), 2, 'r', 'filled'); %% Add legends
                        pt=(Pl_P-TR)*Rot';
                        app.axpt=scatter3(app.UIAxes2, pt(:,1), pt(:,2), pt(:,3), 2, 'b', 'filled'); %% Add Legend
                        hold(app.UIAxes2, 'off');
                        drawnow;
                    view(app.UIAxes2, 30, 20);
                    axis(app.UIAxes2, 'equal');
                    [R,T,Err(k+1)]=Rotation_Umeyama(app,BIM_P,Pl_P);  
                    addpoints(app.RMSELine, k,Err(k+1)); %% fix the iterative figure show
                    drawnow;
                    if abs(Err(k+1)-Err(k))<=RMSE_Th || k>maxIter % Condition that must be met
                       il=1;
                       Assign(I_P)=1;
                       Properties(1)=length(I_P)/nb;
                       Properties(2)=mean(Ar_Sim(I_B));
                       Properties(3)=mean(Ar_Sim(I_B))*length(I_P)/nb;
                       Properties(4)=Err(k);
                       Properties(5)=sqrt(mean(vecnorm((Pl_P-TR)*Rot'-BIM_P,2,2).^2));           
                       ii=sub2ind([nb,np],I_B,I_P);
                       as(ii)=1;
                       All_Assign=as;
                       as(ii)=Ar_Sim(I_B);
                       All_Coverage=as;
                       as1=as;

                       Mn=cumsum([0,ll']);

                       for tt=1:length(I_P)
                           iz=Mn(tt)+1:Mn(tt+1);
                           as(I_B(tt),I_P(tt))=sqrt(mean(vecnorm(Pl_P(iz,:)-BIM_P(iz,:),2,2).^2));
                           as1(I_B(tt),I_P(tt))=sqrt(mean(vecnorm((Pl_P(iz,:)-TR)*Rot'-BIM_P(iz,:),2,2).^2));
                       end
                       All_Accuracy=as;
                       All_ConstErr=as1;
                       Algorithm(1)=k;
                       Algorithm(2)=toc;
                       Algorithm(3)=sum(ll);
        
                    else
                       k=k+1;
                       Rot=Rot*R;
                       TR=TR*R+T;
                    end
                    
                else
                    il=1;
                    Properties=nan(1,5);
                    Algorithm=nan(1,3);
                    All_Assign=[];
                    All_Accuracy=[];
                    All_ConstErr=[];
                    All_Coverage=[];
                end
            end
        end

        function [ID_BIM,Ar_Sim]=BIM_Planar_Correspondance_FIM(app,Bounds_BIM,BIM_mean,Area_BIM,Att_BIM,Att_P,Bounds_P,Ar_Thresh,N_Thresh,D_Thresh)
            n_BIM=size(Att_BIM,1);
            n_Pl=size(Att_P,1);
            N_Dis=acos(abs(Att_BIM(:,1:3)*Att_P(:,1:3)'));
            dN=BIM_mean*Att_P(:,1:3)';
            D_Dis=-(repmat(Att_P(:,4)',n_BIM,1)+dN)./(Att_BIM(:,1:3)*Att_P(:,1:3)');    
            Pot=intersect(find(abs(N_Dis)<=N_Thresh),find(abs(D_Dis)<=D_Thresh));
            BIM_Pot=zeros(n_BIM,n_Pl);
            BIM_Pot(Pot)=1;
            il=find(sum(BIM_Pot,2)>0);
            ID_BIM=zeros(n_BIM,1);
            Ar_Sim=zeros(n_BIM,1);
            Ar=zeros(n_BIM,n_Pl);
            warning ('off','all');
            for i=1:length(il)
                i_p=find(BIM_Pot(il(i),:)==1);
                
                for j=1:length(i_p)
                    Rt=Rodrigues(app,Att_P(i_p(j),1:3),[0,0,1]);
                    Proj=Bounds_BIM{il(i),1}*Rt;
                    Box=Bounds_P{i_p(j),1}(:,1:3)*Rt; % Rotate the boundaries, now we can see if the point Proj_Rot is inside Box or not in 2D
                    pgonB = polyshape(Box(:,1),Box(:,2));
                    pgonP = polyshape(Proj(:,1),Proj(:,2));
                    polyout = intersect(pgonB,pgonP);
                    arj = area(polyout);
                    if arj<Area_BIM(il(i))*Ar_Thresh || abs(area(pgonB)-Area_BIM(il(i)))/Area_BIM(il(i))>2*Ar_Thresh
                       BIM_Pot(il(i),i_p(j))=0;
                    else
                        Ar(il(i),i_p(j))=arj/Area_BIM(il(i));
                    end
                    
                end
        
            end
            if ~isempty(find(BIM_Pot==1, 1))
                I_BIM=100*ones(n_BIM,n_Pl);
                Cost=(abs(N_Dis)*D_Thresh+abs(D_Dis)*N_Thresh);
                I_BIM(BIM_Pot==1)=Cost(BIM_Pot==1);
                M = matchpairs(I_BIM,D_Thresh);
                ID_BIM(M(:,1))=M(:,2);
                ii=sub2ind([n_BIM,n_Pl],M(:,1),M(:,2));
                Ar_Sim(M(:,1))=Ar(ii);
            end
        end
        
        %% 3- Rodrigues Rotation
        % Rodrigues's formula for Vector Rotation: This function provides the
        % rotation matrix to rotate vector N to V. N and V are 1x3 matrices
        function Rot=Rodrigues(app,N,V)
            teta=acos(dot(N,V)/norm(N)/norm(V));
            k=cross(N,V);
            if norm(k)==0
                Rot=eye(length(N),length(N));
            else
                k=k/norm(k);
                K=[0,-k(3),k(2);k(3),0,-k(1);-k(2),k(1),0];
                R=eye(3)+sin(teta)*K+(1-cos(teta))*K^2;
                Rot=R';
            end
        end
        
        %% 4- RBT using Umeyama
        function [R,Tr,RMSE]=Rotation_Umeyama(app,BIM_P,Pl_P)
            % Nd: Destination Vector
            % Ns: Source Vector
            md=mean(BIM_P);
            ms=mean(Pl_P);
            Ndd=BIM_P-md;
            Nss=Pl_P-ms;
            % U*Nss'=Ndd
            M=Nss'*Ndd;
            [V,~,W]=svd(M);
            S=eye(size(BIM_P,2));
            if det(V*W)<0
                S(end,end)=-1;
            end
            R=V*S*W';
            Tr=md-ms*R;
            RMSE=sqrt(mean(vecnorm(BIM_P-Pl_P*R-Tr,2,2).^2));
        end

        %% 5- Recovering Planar Points based on boundary, number and noise
        function [Planar,Bound]=Planar_Generation(app,Att_P,Bounds_P,kk,Sd)
                n_Pl=size(Att_P,1);
                Planar=cell(n_Pl,1);
                un=unique(Bounds_P(:,7));
                Bound=cell(n_Pl,1);
                warning ('off','all');
                for j=1:n_Pl
                    Rt=Rodrigues(app,Att_P(j,1:3),[0,0,1]);
                    ii=find(Bounds_P(:,7)==un(j));
                    Box=Bounds_P(ii,1:3)*Rt;
                    [V,~]=eig(cov(Box(:,1:2)));
                    ang=atan2(V(2,1),V(1,1));
                    r2=[cos(ang),sin(ang),0;-sin(ang),cos(ang),0;0,0,1];
                    B=Box*r2;
                    pgonB = polyshape(B(:,1),B(:,2));
                    sz=size(Box,1);
                    pn=kk*sz;
                    P=zeros(pn,3);
                    P(:,1:2) = randpoly(app,pn,pgonB);
                    P(:,3)=normrnd(mean(Box(:,3)),Sd,pn,1);
                    Planar{j,1}=[P*r2'*Rt',repmat(Bounds_P(ii(1),4:6),pn,1)];
                    Bound{j,1}=Bounds_P(ii,:);
                end
        end
        
        %% 6- Random Uniform Generation on Plane
        function X_Y = randpoly(app,N,PS)
            % Copyright (c) 2023, John D'Errico
            % All rights reserved.
            % 
            % Redistribution and use in source and binary forms, with or without
            % modification, are permitted provided that the following conditions are met:
            % 
            % * Redistributions of source code must retain the above copyright notice, this
            %   list of conditions and the following disclaimer.
            % 
            % * Redistributions in binary form must reproduce the above copyright notice,
            %   this list of conditions and the following disclaimer in the documentation
            %   and/or other materials provided with the distribution
            % 
            % * Neither the name of Retired nor the names of its
            %   contributors may be used to endorse or promote products derived from this
            %   software without specific prior written permission.
            % 
            % THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS IS"
            % AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED TO, THE
            % IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE ARE
            % DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT OWNER OR CONTRIBUTORS BE LIABLE
            % FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL, EXEMPLARY, OR CONSEQUENTIAL
            % DAMAGES (INCLUDING, BUT NOT LIMITED TO, PROCUREMENT OF SUBSTITUTE GOODS OR
            % SERVICES; LOSS OF USE, DATA, OR PROFITS; OR BUSINESS INTERRUPTION) HOWEVER
            % CAUSED AND ON ANY THEORY OF LIABILITY, WHETHER IN CONTRACT, STRICT LIABILITY,
            % OR TORT (INCLUDING NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE
            % OF THIS SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.

            % Modified from: John D'Errico (2025). randpoly 
            % (https://www.mathworks.com/matlabcentral/fileexchange/125580-randpoly), 
            % MATLAB Central File Exchange. Retrieved March 11, 2025.
            
            tr = triangulation(PS);
            Area = area(PS);
            A = tr.Points(tr.ConnectivityList(:,1),:);
            B = tr.Points(tr.ConnectivityList(:,2),:);
            C = tr.Points(tr.ConnectivityList(:,3),:);
            ac = A - C;
            bc = B - C;
            trArea = abs(ac(:,1).*bc(:,2) - ac(:,2).*bc(:,1))/2;
            Wt = trArea./Area;
            pp = rand(N,1);
            pbins = discretize(pp,[0;cumsum(Wt(:))]);
            rr = rand(N,1);    Uv = (1-rr).*tr.Points(tr.ConnectivityList(pbins,1),:) + rr.*tr.Points(tr.ConnectivityList(pbins,2),:);
            qc = sqrt(rand(N,1));
            X_Y = qc.*Uv + (1-qc).*tr.Points(tr.ConnectivityList(pbins,3),:);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            un=unique(app.B_B(:,4:5),'rows');
            for i=1:size(un,1)
                ii=intersect(find(app.B_B(:,4)-un(i,1)==0),find(app.B_B(:,5)-un(i,2)==0));
                xx=app.B_B(ii,1:3);
                patch(app.UIAxes,xx(:,1),xx(:,2),xx(:,3),[0 0.4470 0.7410],'FaceAlpha',0.5)
                view(app.UIAxes, 30, 20);
                axis(app.UIAxes, 'equal');
            end
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            view(app.UIAxes, 30, 20);
        end

        % Button pushed function: xyButton
        function xyButtonPushed(app, event)
            view(app.UIAxes, 2);
        end

        % Button pushed function: xzButton
        function xzButtonPushed(app, event)
            view(app.UIAxes, 0, 0);
        end

        % Button pushed function: yzButton
        function yzButtonPushed(app, event)
            view(app.UIAxes, 90, 0);
        end

        % Button pushed function: GeneratePointCloudButton
        function GeneratePointCloudButtonPushed(app, event)
            app.Lamp.Color='r';
            app.Lamp_2.Color='r';
            app.Lamp_3.Color='r';
            app.Lamp_4.Color='r'; 
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            app.Lamp.Color='y';
            un=unique(app.B_B(:,4:5),'rows');
            
            for i=1:size(un,1)
                ii=intersect(find(app.B_B(:,4)-un(i,1)==0),find(app.B_B(:,5)-un(i,2)==0));
                xx=app.B_B(ii,1:3);
                patch(app.UIAxes,xx(:,1),xx(:,2),xx(:,3),[0 0.4470 0.7410],'FaceAlpha',0.5)
                view(app.UIAxes, 30, 20);
                axis(app.UIAxes, 'equal');
            end
            drawnow;
            sp=app.MeasurementErrormmSlider.Value/1000;
            An=ceil(app.PointDensityMultiplierSlider.Value);
            BP=app.B_P;
            [app.X_P,app.Bound_P]=Planar_Generation(app,app.At_P,BP,An,sp);
            P=cell2mat(app.X_P);
            hold(app.UIAxes, 'on');
            app.Cloud=scatter3(app.UIAxes, P(:,1), P(:,2), P(:,3), 4, P(:,4:6), 'filled');
            hold(app.UIAxes, 'off');
            app.Lamp.Color='g';
            app.ShowPointCloudSwitch.Value='On';
        end

        % Button pushed function: DetectSingleBIMElementButton
        function DetectSingleBIMElementButtonPushed(app, event)
            app.Lamp_2.Color='r';
            app.Lamp_3.Color='r';
            app.Lamp_4.Color='r';
            app.ShowPointCloudSwitch.Value='Off';            
            Area_Th=app.CoverageThresholdSlider.Value/100;
            Dir_Th=app.AngularThresholdcircSlider.Value*pi/180;
            Dis_Th=app.DistanceThresholdcmSlider.Value/100;
            RMSE_Th=app.RMSEThresholdmumSlider.Value/10000000;
            maxIter=100;
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            i=app.BIMElementIDSpinner.Value;
            app.Lamp_2.Color='y';
            un=unique(app.B_B(:,4:5),'rows');

            for t=1:size(un,1)
                ii=intersect(find(app.B_B(:,4)-un(t,1)==0),find(app.B_B(:,5)-un(t,2)==0));
                xx=app.B_B(ii,1:3);
                patch(app.UIAxes,xx(:,1),xx(:,2),xx(:,3),[0 0.4470 0.7410],'FaceAlpha',0.5)
                view(app.UIAxes, 30, 20);
                axis(app.UIAxes, 'equal');
            end

            il=find(app.B_B(:,4)==i);
            ui=unique(app.B_B(il,5));
            lui=size(ui,1);
            Bound_B=cell(lui,1);
            Ari_B=zeros(lui,1);
            Ati_B=zeros(lui,4);
            mi_B=zeros(lui,3);

            for j=1:lui
                ll=app.B_B(il,5)==ui(j);
                Bound_B{j,1}=app.B_B(il(ll),1:3);
                mi_B(j,:)=app.m_B(intersect(find(app.m_B(:,4)==i),find(app.m_B(:,5)==ui(j))),1:3);
                Ati_B(j,:)=app.At_B(intersect(find(app.At_B(:,5)==i),find(app.At_B(:,6)==ui(j))),1:4);
                Ari_B(j,:)=app.Ar_B(intersect(find(app.Ar_B(:,2)==i),find(app.Ar_B(:,3)==ui(j))),1);
            end

            for j=1:lui
                xx=Bound_B{j,1}(:,1:3);
                patch(app.UIAxes,xx(:,1),xx(:,2),xx(:,3),'r','FaceAlpha',0.5);
            end                        
            drawnow
            app.Lamp_3.Color='y';
            app.Lamp_4.Color='y';
            [app.Assign(i,:),app.Property(i,:)]=Assignment_Plane_Plane_FIM(app,Bound_B,mi_B,Ari_B,Ati_B,app.At_P,app.Bound_P,app.X_P,app.m_P,Area_Th,Dir_Th,Dis_Th,RMSE_Th,maxIter);
            app.Lamp_2.Color='g';
            app.Lamp_3.Color='g';
            app.Lamp_4.Color='g';  
            if isnan(app.Property(i,1))
                app.FinalRMSEcmEditField.Value=Inf;
                app.TotalErrorcmEditField.Value=Inf;
            else
                app.FinalRMSEcmEditField.Value=app.Property(i,4)*100;
                app.TotalErrorcmEditField.Value=app.Property(i,5)*100;
            end
        end

        % Button pushed function: DefaultButton_2
        function DefaultButton_2Pushed(app, event)
                view(app.UIAxes2, 30, 20);
        end

        % Button pushed function: xyButton_2
        function xyButton_2Pushed(app, event)
                view(app.UIAxes2, 2);
        end

        % Button pushed function: xzButton_2
        function xzButton_2Pushed(app, event)
                view(app.UIAxes2, 0, 0);
        end

        % Button pushed function: yzButton_2
        function yzButton_2Pushed(app, event)
                view(app.UIAxes2, 90, 0);
        end

        % Value changed function: ShowPointCloudSwitch
        function ShowPointCloudSwitchValueChanged(app, event)
            value = app.ShowPointCloudSwitch.Value;
            if isequal(value,'On')
                P=cell2mat(app.X_P);
                hold(app.UIAxes, 'on');
                app.Cloud=scatter3(app.UIAxes, P(:,1), P(:,2), P(:,3), 4, P(:,4:6), 'filled');
                hold(app.UIAxes, 'off');
            else
                delete(app.Cloud);
            end
        end

        % Button pushed function: DetectionButton
        function DetectionButtonPushed(app, event)
            cla(app.UIAxes)
            app.ShowPointCloudSwitch.Value='Off';
            delete(app.ColorBar);
            for i=1:size(app.Property,1)
                if app.Property(i,2)<=app.CoverageThresholdSlider.Value/100 || isnan(app.Property(i,2))
                    col='r';
                elseif abs(app.Property(i,4)-app.Property(i,5))<=3*app.Property(i,4)
                    col='g';
                else
                    col='y';
                end
                ii=find(app.B_B(:,4)==i);
                ui=unique(app.B_B(ii,5));
                for j=1:length(ui)
                    ij=app.B_B(ii,5)==ui(j);
                    xx=app.B_B(ii(ij),1:3);
                    patch(app.UIAxes,xx(:,1),xx(:,2),xx(:,3),col,'FaceAlpha',0.5)
                end
            end

        end

        % Button pushed function: AccuracyButton
        function AccuracyButtonPushed(app, event)
            app.ShowPointCloudSwitch.Value='Off';
            cla(app.UIAxes)
            delete(app.ColorBar);
            Ap=app.Property(:,4);
            mAp=min(Ap);
            MAp=max(Ap);
            cmap = parula(256);  % 'parula' colormap with 256 entries
            index = ceil((Ap-mAp)/(MAp-mAp) .* size(cmap, 1));  
            index=min(index,size(cmap,1));
            index=max(index,1);
            for i=1:size(app.Property,1)
                ii=find(app.B_B(:,4)==i);
                if ~isnan(app.Property(i,2)) %% app.Property(i,2)>app.CoverageThresholdSlider.Value/100
                    colors = cmap(index(i), :);
                    tt=0.5;
                    tl=1;
                else
                    tt=0;
                    tl=0;
                    colors = 'w';
                end
                ui=unique(app.B_B(ii,5));
                for j=1:length(ui)
                    ij=app.B_B(ii,5)==ui(j);
                    xx=app.B_B(ii(ij),1:3);
                    patch(app.UIAxes,xx(:,1),xx(:,2),xx(:,3),colors,'FaceAlpha',tt,'EdgeAlpha',tl)
                end

            end
            colormap(app.UIAxes, 'parula');
            app.ColorBar = colorbar(app.UIAxes);
            tickMarks = round(linspace(mAp*100, MAp*100, 5),2);
            app.ColorBar.Ticks = linspace(0, 1, 5);
            app.ColorBar.TickLabels = arrayfun(@(x) sprintf('%.2f', x), tickMarks, 'UniformOutput', false);
            app.ColorBar.Label.String = 'RMSE (cm)';  % Replace 'Temperature (°C)' with your units
            app.ColorBar.Label.FontSize = 16;               % Set font size
            app.ColorBar.Label.FontWeight = 'bold';
            app.ColorBar.Limits = [0,1];
        end

        % Button pushed function: CoverageButton
        function CoverageButtonPushed(app, event)
            cla(app.UIAxes)
            app.ShowPointCloudSwitch.Value='Off';
            delete(app.ColorBar);
            Ap=app.Property(:,2);
            mAp=min(Ap);
            MAp=max(Ap);
            cmap = parula(256);  % 'parula' colormap with 256 entries
            index = ceil((Ap-mAp)/(MAp-mAp) .* size(cmap, 1));  
            index=min(index,size(cmap,1));
            index=max(index,1);
            for i=1:size(app.Property,1)
                ii=find(app.B_B(:,4)==i);
                if ~isnan(app.Property(i,2))
                    colors = cmap(index(i), :);
                    tt=0.5;
                    tl=1;
                else
                    tt=0;
                    tl=0;
                    colors = 'w';
                end
                ui=unique(app.B_B(ii,5));
                for j=1:length(ui)
                    ij=app.B_B(ii,5)==ui(j);
                    xx=app.B_B(ii(ij),1:3);
                    patch(app.UIAxes,xx(:,1),xx(:,2),xx(:,3),colors,'FaceAlpha',tt,'EdgeAlpha',tl)
                end

            end
            colormap(app.UIAxes, 'parula');
            app.ColorBar = colorbar(app.UIAxes);
            tickMarks = round(linspace(mAp*100, MAp*100, 5),2);
            app.ColorBar.Ticks = linspace(0, 1, 5);
            app.ColorBar.TickLabels = arrayfun(@(x) sprintf('%.2f', x), tickMarks, 'UniformOutput', false);
            app.ColorBar.Label.String = 'Coverage (%)';  % Replace 'Temperature (°C)' with your units
            app.ColorBar.Label.FontSize = 16;               % Set font size
            app.ColorBar.Label.FontWeight = 'bold';
            app.ColorBar.Limits = [0,1];
        end

        % Button pushed function: TotalErrorButton
        function TotalErrorButtonPushed(app, event)
            cla(app.UIAxes)
            app.ShowPointCloudSwitch.Value='Off';
            delete(app.ColorBar);
            Ap=app.Property(:,5);
            mAp=min(Ap);
            MAp=max(Ap);
            cmap = jet(256);  % 'parula' colormap with 256 entries
            index = ceil((Ap-mAp)/(MAp-mAp) .* size(cmap, 1));  
            index=min(index,size(cmap,1));
            index=max(index,1);
            for i=1:size(app.Property,1)
                ii=find(app.B_B(:,4)==i);
                if ~isnan(app.Property(i,2))
                    colors = cmap(index(i), :);
                    tt=0.25;
                    tl=1;
                else
                    colors = 'w';
                    tt=0;
                    tl=0;
                end
                ui=unique(app.B_B(ii,5));
                for j=1:length(ui)
                    ij=app.B_B(ii,5)==ui(j);
                    xx=app.B_B(ii(ij),1:3);
                    patch(app.UIAxes,xx(:,1),xx(:,2),xx(:,3),colors,'FaceAlpha',tt,'EdgeAlpha',tl)
                end

            end
            colormap(app.UIAxes, 'jet');
            app.ColorBar = colorbar(app.UIAxes);
            tickMarks = round(linspace(mAp*100, MAp*100, 5),2);
            app.ColorBar.Ticks = linspace(0, 1, 5);
            app.ColorBar.TickLabels = arrayfun(@(x) sprintf('%.2f', x), tickMarks, 'UniformOutput', false);
            app.ColorBar.Label.String = 'Construction Error (cm)';  % Replace 'Temperature (°C)' with your units
            app.ColorBar.Label.FontSize = 20;               % Set font size
            app.ColorBar.Label.FontWeight = 'bold';
            app.ColorBar.Limits = [0,1];
        end

        % Button pushed function: DetectAllBIMElementsButton
        function DetectAllBIMElementsButtonPushed(app, event)
        for i=1:max(app.At_B(:,5))
            app.BIMElementIDSpinner.Value=i;
            app.Lamp_2.Color='r';
            app.Lamp_3.Color='r';
            app.Lamp_4.Color='r';
            app.ShowPointCloudSwitch.Value='Off';            
            Area_Th=app.CoverageThresholdSlider.Value/100;
            Dir_Th=app.AngularThresholdcircSlider.Value*pi/180;
            Dis_Th=app.DistanceThresholdcmSlider.Value/100;
            RMSE_Th=app.RMSEThresholdmumSlider.Value/10000000;
            maxIter=100;
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            app.Lamp_2.Color='y';
            un=unique(app.B_B(:,4:5),'rows');

            for t=1:size(un,1)
                ii=intersect(find(app.B_B(:,4)-un(t,1)==0),find(app.B_B(:,5)-un(t,2)==0));
                xx=app.B_B(ii,1:3);
                patch(app.UIAxes,xx(:,1),xx(:,2),xx(:,3),[0 0.4470 0.7410],'FaceAlpha',0.5)
                view(app.UIAxes, 30, 20);
                axis(app.UIAxes, 'equal');
            end

            il=find(app.B_B(:,4)==i);
            ui=unique(app.B_B(il,5));
            lui=size(ui,1);
            Bound_B=cell(lui,1);
            Ari_B=zeros(lui,1);
            Ati_B=zeros(lui,4);
            mi_B=zeros(lui,3);

            for j=1:lui
                ll=app.B_B(il,5)==ui(j);
                Bound_B{j,1}=app.B_B(il(ll),1:3);
                mi_B(j,:)=app.m_B(intersect(find(app.m_B(:,4)==i),find(app.m_B(:,5)==ui(j))),1:3);
                Ati_B(j,:)=app.At_B(intersect(find(app.At_B(:,5)==i),find(app.At_B(:,6)==ui(j))),1:4);
                Ari_B(j,:)=app.Ar_B(intersect(find(app.Ar_B(:,2)==i),find(app.Ar_B(:,3)==ui(j))),1);
            end

            for j=1:lui
                xx=Bound_B{j,1}(:,1:3);
                patch(app.UIAxes,xx(:,1),xx(:,2),xx(:,3),'r','FaceAlpha',0.5);
            end                        
            drawnow
            app.Lamp_3.Color='y';
            app.Lamp_4.Color='y';
            [app.Assign(i,:),app.Property(i,:)]=Assignment_Plane_Plane_FIM(app,Bound_B,mi_B,Ari_B,Ati_B,app.At_P,app.Bound_P,app.X_P,app.m_P,Area_Th,Dir_Th,Dis_Th,RMSE_Th,maxIter);
            
            if isnan(app.Property(i,1))
                app.FinalRMSEcmEditField.Value=Inf;
                app.TotalErrorcmEditField.Value=Inf;
            else
                app.FinalRMSEcmEditField.Value=app.Property(i,4)*100;
                app.TotalErrorcmEditField.Value=app.Property(i,5)*100;
            end
            
            app.Lamp_2.Color='g';
            app.Lamp_3.Color='g';
            app.Lamp_4.Color='g';  
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'BIM and Point Cloud')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z (m)')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Point Cloud Assignment to BIM')
            xlabel(app.UIAxes2, 'X (m)')
            ylabel(app.UIAxes2, 'Y (m)')
            zlabel(app.UIAxes2, 'Z (m)')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Convergence')
            xlabel(app.UIAxes3, 'Iteration')
            ylabel(app.UIAxes3, 'Objective Function')
            zlabel(app.UIAxes3, 'Z')
            app.GeneratePointCloudButton = uibutton(app.GridLayout, 'push');
            app.GeneratePointCloudButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePointCloudButtonPushed, true);
            app.GeneratePointCloudButton.Text = 'Generate Point Cloud';
            app.RotationAngleConstructionErrorLabel = uilabel(app.GridLayout);
            app.RotationAngleConstructionErrorLabel.Text = {'Point Density'; 'Multiplier'};
            app.PointDensityMultiplierSlider = uislider(app.GridLayout);
            app.PointDensityMultiplierSlider.Limits = [20 100];
            app.PointDensityMultiplierSlider.Value = 25;
            app.RMSE_ThreshmmLabel = uilabel(app.GridLayout);
            app.RMSE_ThreshmmLabel.Interpreter = 'tex';
            app.RMSE_ThreshmmLabel.Text = {'RMSE'; 'Threshold (\mum)'};
            app.RMSEThresholdmumSlider = uislider(app.GridLayout);
            app.RMSEThresholdmumSlider.Limits = [0.1 10];
            app.RMSEThresholdmumSlider.MajorTicks = [0.1 3 6 10];
            app.RMSEThresholdmumSlider.Value = 0.1;
            app.Distance_ThreshcmLabel = uilabel(app.GridLayout);
            app.Distance_ThreshcmLabel.Interpreter = 'tex';
            app.Distance_ThreshcmLabel.Text = {'Distance '; 'Threshold (cm)'};
            app.DistanceThresholdcmSlider = uislider(app.GridLayout);
            app.DistanceThresholdcmSlider.Limits = [5 50];
            app.DistanceThresholdcmSlider.Value = 35;
            app.FinalRMSEcmEditFieldLabel = uilabel(app.GridLayout);
            app.FinalRMSEcmEditFieldLabel.Text = {'Final RMSE '; '(cm)'};
            app.FinalRMSEcmEditField = uieditfield(app.GridLayout, 'numeric');
            app.TotalErrorcmEditFieldLabel = uilabel(app.GridLayout);
            app.TotalErrorcmEditFieldLabel.Text = {'Total Error '; '(cm)'};
            app.TotalErrorcmEditField = uieditfield(app.GridLayout, 'numeric');
            app.Lamp = uilamp(app.GridLayout);
            app.Lamp_2 = uilamp(app.GridLayout);
            app.Lamp_3 = uilamp(app.GridLayout);
            app.Lamp_4 = uilamp(app.GridLayout);
            app.PointCloudGeneratedLabel = uilabel(app.GridLayout);
            app.PointCloudGeneratedLabel.Text = 'Point Cloud Generated';
            app.BIMDetectionCompletedLabel = uilabel(app.GridLayout);
            app.BIMDetectionCompletedLabel.Text = 'BIM Detection Completed';
            app.ViewPanel = uipanel(app.GridLayout);
            app.ViewPanel.Title = 'View';
            app.DefaultButton = uibutton(app.ViewPanel, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.xyButton = uibutton(app.ViewPanel, 'push');
            app.xyButton.ButtonPushedFcn = createCallbackFcn(app, @xyButtonPushed, true);
            app.xyButton.Text = 'x-y';
            app.xzButton = uibutton(app.ViewPanel, 'push');
            app.xzButton.ButtonPushedFcn = createCallbackFcn(app, @xzButtonPushed, true);
            app.xzButton.Text = 'x-z';
            app.yzButton = uibutton(app.ViewPanel, 'push');
            app.yzButton.ButtonPushedFcn = createCallbackFcn(app, @yzButtonPushed, true);
            app.yzButton.Text = 'y-z';
            app.MeasurementErrormmLabel = uilabel(app.GridLayout);
            app.MeasurementErrormmLabel.Text = {'Measurement '; 'Error (mm)'};
            app.MeasurementErrormmSlider = uislider(app.GridLayout);
            app.MeasurementErrormmSlider.Limits = [3 8];
            app.MeasurementErrormmSlider.Value = 3;
            app.AcceptableCoverageLabel = uilabel(app.GridLayout);
            app.AcceptableCoverageLabel.Interpreter = 'tex';
            app.AcceptableCoverageLabel.Text = {'Coverage'; 'Threshold (%)'};
            app.CoverageThresholdSlider = uislider(app.GridLayout);
            app.CoverageThresholdSlider.Value = 75;
            app.AngularThresholdcircSliderLabel = uilabel(app.GridLayout);
            app.AngularThresholdcircSliderLabel.Interpreter = 'tex';
            app.AngularThresholdcircSliderLabel.Text = {'Angular '; 'Threshold (\circ)'};
            app.AngularThresholdcircSlider = uislider(app.GridLayout);
            app.AngularThresholdcircSlider.Limits = [5 20];
            app.AngularThresholdcircSlider.Value = 12;
            app.DetectSingleBIMElementButton = uibutton(app.GridLayout, 'push');
            app.DetectSingleBIMElementButton.ButtonPushedFcn = createCallbackFcn(app, @DetectSingleBIMElementButtonPushed, true);
            app.DetectSingleBIMElementButton.Text = 'Detect Single BIM Element';
            app.ColourMapPanel = uipanel(app.GridLayout);
            app.ColourMapPanel.Title = 'Colour Map';
            app.AccuracyButton = uibutton(app.ColourMapPanel, 'push');
            app.AccuracyButton.ButtonPushedFcn = createCallbackFcn(app, @AccuracyButtonPushed, true);
            app.AccuracyButton.Text = 'Accuracy';
            app.CoverageButton = uibutton(app.ColourMapPanel, 'push');
            app.CoverageButton.ButtonPushedFcn = createCallbackFcn(app, @CoverageButtonPushed, true);
            app.CoverageButton.Text = 'Coverage';
            app.DetectionButton = uibutton(app.ColourMapPanel, 'push');
            app.DetectionButton.ButtonPushedFcn = createCallbackFcn(app, @DetectionButtonPushed, true);
            app.DetectionButton.Text = 'Detection';
            app.TotalErrorButton = uibutton(app.ColourMapPanel, 'push');
            app.TotalErrorButton.ButtonPushedFcn = createCallbackFcn(app, @TotalErrorButtonPushed, true);
            app.TotalErrorButton.Text = 'Total Error';
            app.BIMElementIDSpinnerLabel = uilabel(app.GridLayout);
            app.BIMElementIDSpinnerLabel.Interpreter = 'tex';
            app.BIMElementIDSpinnerLabel.Text = 'BIM Element ID';
            app.BIMElementIDSpinner = uispinner(app.GridLayout);
            app.BIMElementIDSpinner.Limits = [1 30];
            app.BIMElementIDSpinner.Value = 1;
            app.xyButton_2 = uibutton(app.GridLayout, 'push');
            app.xyButton_2.ButtonPushedFcn = createCallbackFcn(app, @xyButton_2Pushed, true);
            app.xyButton_2.Text = 'x-y';
            app.xzButton_2 = uibutton(app.GridLayout, 'push');
            app.xzButton_2.ButtonPushedFcn = createCallbackFcn(app, @xzButton_2Pushed, true);
            app.xzButton_2.Text = 'x-z';
            app.yzButton_2 = uibutton(app.GridLayout, 'push');
            app.yzButton_2.ButtonPushedFcn = createCallbackFcn(app, @yzButton_2Pushed, true);
            app.yzButton_2.Text = 'y-z';
            app.DefaultButton_2 = uibutton(app.GridLayout, 'push');
            app.DefaultButton_2.ButtonPushedFcn = createCallbackFcn(app, @DefaultButton_2Pushed, true);
            app.DefaultButton_2.Text = 'Default';
            app.ShowPointCloudSwitchLabel = uilabel(app.GridLayout);
            app.ShowPointCloudSwitchLabel.Text = 'Show Point Cloud';
            app.ShowPointCloudSwitch = uiswitch(app.GridLayout, 'slider');
            app.ShowPointCloudSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowPointCloudSwitchValueChanged, true);
            app.DetectAllBIMElementsButton = uibutton(app.GridLayout, 'push');
            app.DetectAllBIMElementsButton.ButtonPushedFcn = createCallbackFcn(app, @DetectAllBIMElementsButtonPushed, true);
            app.DetectAllBIMElementsButton.Text = 'Detect All BIM Elements';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Point_Cloud_FIM_Planar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end